classdef ISETBioCSFGenerator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        mainView                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        TabGroupB                       matlab.ui.container.TabGroup
        opticsTab                       matlab.ui.container.Tab
        Panel_3                         matlab.ui.container.Panel
        subjectIDSpinnerLabel           matlab.ui.control.Label
        opticsSubjectIDSpinner          matlab.ui.control.Spinner
        subjectdatasetLabel             matlab.ui.control.Label
        opticsSubjectDataBaseDropDown   matlab.ui.control.DropDown
        pupilsizemmLabel                matlab.ui.control.Label
        opticsPupilSizeSpinner          matlab.ui.control.Spinner
        opticsSubtractCentralRefractionCheckBox  matlab.ui.control.CheckBox
        xpositionsLabel                 matlab.ui.control.Label
        opticsPSFGridXPositionsSpinner  matlab.ui.control.Spinner
        ypositionsLabel                 matlab.ui.control.Label
        opticsPSFGridYPositionsSpinner  matlab.ui.control.Spinner
        PSFspatialgridLabel             matlab.ui.control.Label
        opticsVisualizedWavelengthSlider  matlab.ui.control.Slider
        wavelengthnmSliderLabel         matlab.ui.control.Label
        opticsKeepConeMosaicActivationInSyncCheckBox  matlab.ui.control.CheckBox
        wavefrontspatialsamplesLabel    matlab.ui.control.Label
        opticsWavefrontSpatialSamplesSpinner  matlab.ui.control.Spinner
        opticsZeroCenterPSFCheckBox     matlab.ui.control.CheckBox
        opticsFlipPSFUpsideDownCheckBox  matlab.ui.control.CheckBox
        opticsView                      matlab.ui.control.UIAxes
        opticsViewPSFGrid               matlab.ui.control.UIAxes
        coneMosaicTab                   matlab.ui.container.Tab
        Panel_4                         matlab.ui.container.Panel
        coneMosaicViewModeKnob          matlab.ui.control.DiscreteKnob
        coneMosaicActivationTypeSwitch  matlab.ui.control.Switch
        coneMosaicVisualizationDomainSwitch  matlab.ui.control.Switch
        LSpinnerLabel                   matlab.ui.control.Label
        coneMosaicLconeRatioSpinner     matlab.ui.control.Spinner
        MLabel                          matlab.ui.control.Label
        coneMosaicMconeRatioSpinner     matlab.ui.control.Spinner
        SLabel                          matlab.ui.control.Label
        coneMosaicSconeRatioSpinner     matlab.ui.control.Spinner
        coneratiosLabel                 matlab.ui.control.Label
        coneMosaicRegenerateButton      matlab.ui.control.Button
        tritanopicradiusdegreesLabel    matlab.ui.control.Label
        coneMosaicTritanopicRadiusSpinner  matlab.ui.control.Spinner
        integrationtimemsecLabel        matlab.ui.control.Label
        coneMosaicIntegrationTimeSpinner  matlab.ui.control.Spinner
        coneMosaicEccVaryingMacularPigmentDensityCheckBox  matlab.ui.control.CheckBox
        coneMosaicEccVaryingConeApertureCheckBox  matlab.ui.control.CheckBox
        coneMosaicEccVaryingConeApertureBlurCheckBox  matlab.ui.control.CheckBox
        coneMosaicEccVaryingOuterSegmentLengthCheckBox  matlab.ui.control.CheckBox
        coneMosaicEccVaryingMacularPigmentDynamicCheckBox  matlab.ui.control.CheckBox
        coneMosaicActivationSignalSwitch  matlab.ui.control.Switch
        coneMosaicActivationDimensionalitySwitch  matlab.ui.control.Switch
        cpcLabel                        matlab.ui.control.Label
        coneMosaicView                  matlab.ui.control.UIAxes
        fixationalEyeMovementsTab       matlab.ui.container.Tab
        Panel_6                         matlab.ui.container.Panel
        comingsoonLabel_3               matlab.ui.control.Label
        mRGCmosaicTab                   matlab.ui.container.Tab
        Panel_8                         matlab.ui.container.Panel
        comingsoonLabel_2               matlab.ui.control.Label
        pRGCmosaicTab                   matlab.ui.container.Tab
        Panel_9                         matlab.ui.container.Panel
        cominglaterLabel                matlab.ui.control.Label
        computationalObserverTab        matlab.ui.container.Tab
        Panel_11                        matlab.ui.container.Panel
        log10contrastminLabel           matlab.ui.control.Label
        psychometricFunctionLog10ContrastMinSpinner  matlab.ui.control.Spinner
        log10contrastmaxLabel           matlab.ui.control.Label
        psychometricFunctionLog10ContrastMaxSpinner  matlab.ui.control.Spinner
        log10contrastdeltaLabel         matlab.ui.control.Label
        psychometricFunctionLog10ContrastDeltaSpinner  matlab.ui.control.Spinner
        slopeminLabel                   matlab.ui.control.Label
        psychometricFunctionSlopeMinSpinner  matlab.ui.control.Spinner
        slopemaxLabel                   matlab.ui.control.Label
        psychometricFunctionSlopeMaxSpinner  matlab.ui.control.Spinner
        slopedeltaLabel                 matlab.ui.control.Label
        psychometricFunctionSlopeDeltaSpinner  matlab.ui.control.Spinner
        classifierLabel                 matlab.ui.control.Label
        psychometricFunctionClassifierTypeDropDown  matlab.ui.control.DropDown
        trainingtrialsLabel             matlab.ui.control.Label
        psychometricFunctionClassifierTrainingTrialsSpinner  matlab.ui.control.Spinner
        testtrialsLabel                 matlab.ui.control.Label
        psychometricFunctionClassifierTestTrialsSpinner  matlab.ui.control.Spinner
        contrastlevelsLabel             matlab.ui.control.Label
        psychometricFunctionContrastLevelsSpinner  matlab.ui.control.Spinner
        spatialfrequencymincdegLabel    matlab.ui.control.Label
        csfSpatialFrequencyMinSpinner   matlab.ui.control.Spinner
        spatialfrequencymaxcdegLabel    matlab.ui.control.Label
        csfSpatialFrequencyMaxSpinner   matlab.ui.control.Spinner
        spatialfrequencysamplesLabel    matlab.ui.control.Label
        csfSpatialFrequencySamplesSpinner  matlab.ui.control.Spinner
        csfConstantParameterSwitch      matlab.ui.control.Switch
        csfSourceSignalKnob             matlab.ui.control.DiscreteKnob
        psychometricFunctionEstimationMethodSwitch  matlab.ui.control.Switch
        csfNumberOfConstantCyclesSpinner  matlab.ui.control.Spinner
        simulussizeatlowestspatialfrequencydegEditFieldLabel_2  matlab.ui.control.Label
        stimulusSizeAtLowestSpatialFrequencyEditField  matlab.ui.control.EditField
        csfRunButton                    matlab.ui.control.StateButton
        resetCSFRunButton               matlab.ui.control.Button
        signalsourceLabel               matlab.ui.control.Label
        sigmaLabel                      matlab.ui.control.Label
        psychometricfunctionestimationLabel  matlab.ui.control.Label
        psychometricFunctionView        matlab.ui.control.UIAxes
        csfView                         matlab.ui.control.UIAxes
        TabGroupA                       matlab.ui.container.TabGroup
        visualFieldTab                  matlab.ui.container.Tab
        Panel_5                         matlab.ui.container.Panel
        roiEyeSwitch                    matlab.ui.control.Switch
        polareccentricitydegsLabel      matlab.ui.control.Label
        roiPolarEccentricityKnob        matlab.ui.control.Knob
        radialeccentricitydegsLabel     matlab.ui.control.Label
        roiRadialEccentricitySlider     matlab.ui.control.Slider
        magnificationfactormicronsdegLabel  matlab.ui.control.Label
        roiMagnificationFactorEditField  matlab.ui.control.NumericEditField
        fieldofviewdegsLabel            matlab.ui.control.Label
        roiFieldOfViewSlider            matlab.ui.control.Slider
        radialeccentricityscalingLabel  matlab.ui.control.Label
        roiRadialEccentricityScalingSwitch  matlab.ui.control.Switch
        roiEccentricityX                matlab.ui.control.NumericEditField
        roiEccentricityY                matlab.ui.control.NumericEditField
        eccentricitydegsLabel           matlab.ui.control.Label
        roiView                         matlab.ui.control.UIAxes
        stimulusTab                     matlab.ui.container.Tab
        Panel_7                         matlab.ui.container.Panel
        stimulusdurationmsecSpinnerLabel  matlab.ui.control.Label
        stimulusDurationSpinner         matlab.ui.control.Spinner
        meanluminancecdm2SpinnerLabel   matlab.ui.control.Label
        stimulusMeanLuminanceSpinner    matlab.ui.control.Spinner
        temporalfrequencyHzSpinnerLabel  matlab.ui.control.Label
        stimulusTemporalFrequencySpinner  matlab.ui.control.Spinner
        orientationdegLabel             matlab.ui.control.Label
        stimulusOrientationSpinner      matlab.ui.control.Spinner
        presentationmodeLabel           matlab.ui.control.Label
        stimulusPresentationModeDropDown  matlab.ui.control.DropDown
        spatialenvelopeLabel            matlab.ui.control.Label
        stimulusSpatialEnvelopeDropDown  matlab.ui.control.DropDown
        spatialfrequencycdegSpinnerLabel  matlab.ui.control.Label
        stimulusSpatialFrequencySpinner  matlab.ui.control.Spinner
        sizedegSpinnerLabel             matlab.ui.control.Label
        stimulusSizeSpinner             matlab.ui.control.Spinner
        resolutionpixelsSpinnerLabel    matlab.ui.control.Label
        stimulusResolutionSpinner       matlab.ui.control.Spinner
        stimulusSpatialPositionXSpinner  matlab.ui.control.Spinner
        xypositiondegLabel              matlab.ui.control.Label
        stimulusSpatialPositionYSpinner  matlab.ui.control.Spinner
        stimulusMosaicCenteredCheckBox  matlab.ui.control.CheckBox
        LconecontrastSpinnerLabel       matlab.ui.control.Label
        stimulusLconeContrastSpinner    matlab.ui.control.Spinner
        MconecontrastSpinnerLabel       matlab.ui.control.Label
        stimulusMconeContrastSpinner    matlab.ui.control.Spinner
        SconecontrastSpinnerLabel       matlab.ui.control.Label
        stimulusSconeContrastSpinner    matlab.ui.control.Spinner
        wavelengthsupportminnmSpinnerLabel  matlab.ui.control.Label
        stimulusWavelengthSupportMinSpinner  matlab.ui.control.Spinner
        wavelengthsupportmaxnmSpinnerLabel  matlab.ui.control.Label
        stimulusWavelengthSupportMaxSpinner  matlab.ui.control.Spinner
        wavelengthsupportstepsizenmSpinnerLabel  matlab.ui.control.Label
        stimulusWavelengthSupportStepSizeSpinner  matlab.ui.control.Spinner
        stimulusGenerateButton          matlab.ui.control.Button
        spatialphasedegLabel            matlab.ui.control.Label
        stimulusSpatialPhaseSpinner     matlab.ui.control.Spinner
        stimulusView                    matlab.ui.control.UIAxes
        performanceAssessmentTab        matlab.ui.container.Tab
        Panel_10                        matlab.ui.container.Panel
        exportPipelineButton            matlab.ui.control.Button
        exportCSFPDF                    matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        tabGroupAStatusField            matlab.ui.control.EditField
        Panel_2                         matlab.ui.container.Panel
        tabGroupBStatusField            matlab.ui.control.EditField
    end

    
    properties (Access = public)
        % Params for various processing pipeline components
        roiParams                       % struct with all region-of-intererst params
        coneMosaicParams                % struct with all cone mosaic params
        opticsParams                    % struct with all optics params
        stimParams                      % struct with all stimulus params
        psychometricFunctionParams      % struct with all psychometric function params
        csfParams                       % struct with all CSF params
        debugParams                     % struct with various visual debuging params
        
        % Constant params, e.g. parameters of Watson's pyramid of visibility
        constants

        % Components of the processing pipeline
        components                      % struct containing the various processing objects, e.g, coneMosaic, optics, etc.

        % Products of the processing pipeline
        products                        % struct containing the various products, e.g., demoStimulusScene, nullStimulusScene, responses, contrast sensitivity function
        
        % View modes for various components
        viewModes                      % struct containing view modes for different components/products
        
        % Status messages of the processing pipeline
        statusMessages                  % dictionary with status messages for each tab
        
        % Colors for different parts of the GUI
        colors                          % dictionary with colors for different parts of the GUI
        csfLineColors                   % colors for psychometric function line plots for each spatial frequency
        
        exportDirectoryName             % Default export directory location
        
        pdfIndex                        % Index of PDF generated
        
        % Handles to various plots
        opticDiskOnROIPlotHandle        % handle to the optic disk plot
        mosaicOnROIPlotHandle           % handle to the mosaic outline plot
        stimulusOnROIPlotHandle         % handle to the stimulus outline plot
        psfDensityPlotHandle            % handle to the density plot of the PSF
        coneOutlineOnPSFPlotHandles     % array of handles to the cones of PSF plot
        stimulusPlotHandle              % handle to the image plot of the stimulus
        stimulusTextHandle              % handle to the text showing stimulus frame no.
        csfDataPointHandles             % handles to each of the CSF data point scatter plot
        
        % Outlines of cones plotted with the PSF
        centralConeOutlinesArcMin       % x,y coords of central cones in current mosaic
        centralConeOutlinesNum = 7+13;
        
        % XYlimits for various zoomable plots
        opticsViewXLimsArcMin = [];
        opticsViewYLimsArcMin = [];
        coneMosaicViewXLimsDegs = [];
        coneMosaicViewXLimsMicrons = [];
        coneMosaicViewYLimsDegs = [];
        coneMosaicViewYLimsMicrons = [];
        
        resetButtonPressed = false;
    end
    
    methods (Access = public)
        
        % Handler function for optics zoom event
        function handleOpticsZoomEvent(app,src,evnt)
            switch src.Name
                case 'XLim'
                    app.opticsViewXLimsArcMin = evnt.AffectedObject.XLim;
        
                case 'YLim'
                    app.opticsViewYLimsArcMin = evnt.AffectedObject.YLim;
            end
            if (max(app.opticsViewXLimsArcMin) < 2)
                set(app.opticsView, 'XTick', -2:0.5:2);
            elseif (max(app.opticsViewXLimsArcMin) < 5)
                set(app.opticsView, 'XTick', -5:1:5);
            elseif (max(app.opticsViewXLimsArcMin) < 10)
                set(app.opticsView, 'XTick', -10:2:10);
            elseif (max(app.opticsViewXLimsArcMin) < 20)
                set(app.opticsView, 'XTick', -20:4:20);
            else
                set(app.opticsView, 'XTick', -30:5:30);
            end
        end
        
        % Handler function for cone mosaic zoom event
        function handleConeMosaicViewZoomEvent(app,src,evnt)
            switch src.Name
                case 'XLim'
                    switch (app.viewModes.coneMosaicVisualizationDomain)
                        case 'degrees'
                            app.coneMosaicViewXLimsDegs = evnt.AffectedObject.XLim;
                        case 'microns'
                            app.coneMosaicViewXLimsMicrons = evnt.AffectedObject.XLim;
                    end
                case 'YLim'
                    switch (app.viewModes.coneMosaicVisualizationDomain)
                        case 'degrees'
                            app.coneMosaicViewYLimsDegs = evnt.AffectedObject.YLim;
                        case 'microns'
                            app.coneMosaicViewYLimsMicrons = evnt.AffectedObject.YLim;
                    end
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            CSFGeneratorApp.initialize.simulationParams(app);
            CSFGeneratorApp.initialize.guiComponents(app);
            CSFGeneratorApp.initialize.allViews(app);
            CSFGeneratorApp.generate.processingPipeline(app, 'Starting up.');
             
            CSFGeneratorApp.render.roiView(app, 'update');
            CSFGeneratorApp.render.coneMosaicView(app, 'update');
        end

        % Value changed function: roiEyeSwitch
        function roiEyeSwitchValueChanged(app, event)
            value = app.roiEyeSwitch.Value;
            CSFGeneratorApp.decode.roiEyeSwitch(app, 'sliderToValue', value);
            CSFGeneratorApp.generate.processingPipeline(app, 'Generating new processing pipeline');
            CSFGeneratorApp.render.roiView(app, 'update');
            CSFGeneratorApp.render.coneMosaicView(app, 'update');
        end

        % Value changed function: roiPolarEccentricityKnob
        function roiPolarEccentricityKnobValueChanged(app, event)
            value = app.roiPolarEccentricityKnob.Value;
            CSFGeneratorApp.decode.roiPolarEccentricitySlider(app, 'sliderToValue', value);
            CSFGeneratorApp.render.roiView(app, 'update');
            CSFGeneratorApp.generate.processingPipeline(app, 'Generating new processing pipeline');
            CSFGeneratorApp.render.coneMosaicView(app, 'update');
        end

        % Value changing function: roiPolarEccentricityKnob
        function roiPolarEccentricityKnobValueChanging(app, event)
            changingValue = event.Value;
            CSFGeneratorApp.decode.roiPolarEccentricitySlider(app, 'sliderToValue', changingValue);
            CSFGeneratorApp.render.roiView(app, 'update');
        end

        % Value changed function: roiRadialEccentricitySlider
        function roiRadialEccentricitySliderValueChanged(app, event)
            value = app.roiRadialEccentricitySlider.Value;
            CSFGeneratorApp.decode.roiRadialEccentricitySlider(app, 'sliderToValue', value);
            CSFGeneratorApp.render.roiView(app, 'update');
            CSFGeneratorApp.generate.processingPipeline(app, 'Generating new processing pipeline');
            CSFGeneratorApp.render.coneMosaicView(app, 'update');
        end

        % Value changed function: roiRadialEccentricityScalingSwitch
        function roiRadialEccentricityScalingSwitchValueChanged(app, event)
            value = app.roiRadialEccentricityScalingSwitch.Value;
            CSFGeneratorApp.decode.roiRadialEccentricityScalingSwitch(app, 'sliderToValue', value);
            CSFGeneratorApp.render.roiView(app, 'update');
        end

        % Value changing function: roiRadialEccentricitySlider
        function roiRadialEccentricitySliderValueChanging(app, event)
            changingValue = event.Value;
            CSFGeneratorApp.decode.roiRadialEccentricitySlider(app, 'sliderToValue', changingValue);
            CSFGeneratorApp.render.roiView(app, 'update');
        end

        % Value changed function: roiFieldOfViewSlider
        function roiFieldOfViewSliderValueChanged(app, event)
            value = app.roiFieldOfViewSlider.Value;
            CSFGeneratorApp.decode.roiFieldOfViewSlider(app, 'sliderToValue', value);
            CSFGeneratorApp.render.roiView(app, 'update');
            CSFGeneratorApp.generate.processingPipeline(app, 'Generating new processing pipeline');
            CSFGeneratorApp.render.coneMosaicView(app, 'update');
        end

        % Value changing function: roiFieldOfViewSlider
        function roiFieldOfViewSliderValueChanging(app, event)
            changingValue = event.Value;
            CSFGeneratorApp.decode.roiFieldOfViewSlider(app, 'sliderToValue', changingValue);
            CSFGeneratorApp.render.roiView(app, 'update');
        end

        % Value changed function: opticsVisualizedWavelengthSlider
        function opticsVisualizedWavelengthSliderChanged(app, event)
            value = app.opticsVisualizedWavelengthSlider.Value;
            CSFGeneratorApp.decode.opticsWavelengthSlider(app, 'sliderToValue', value);
            CSFGeneratorApp.render.opticsView(app, 'update');
        end

        % Value changing function: opticsVisualizedWavelengthSlider
        function opticsWavelengthSliderChanging(app, event)
            changingValue = event.Value;
            CSFGeneratorApp.decode.opticsWavelengthSlider(app, 'sliderToValue', changingValue);
            CSFGeneratorApp.render.opticsView(app, 'update');
        end

        % Value changed function: opticsPSFGridXPositionsSpinner
        function opticsPSFGridXPositionsChanged(app, event)
            value = app.opticsPSFGridXPositionsSpinner.Value;
            
        end

        % Value changed function: opticsPSFGridYPositionsSpinner
        function opticsPSFGridYPositionsChanged(app, event)
            value = app.opticsPSFGridYPositionsSpinner.Value;
            
        end

        % Value changed function: opticsSubjectDataBaseDropDown
        function opticsSubjectDataBaseChanged(app, event)
            value = app.opticsSubjectDataBaseDropDown.Value;
            CSFGeneratorApp.decode.opticsSubjectDataBaseDropdown(app, 'sliderToValue', value);
            % generate optics for subject 1
            CSFGeneratorApp.decode.opticsSubjectIDSpinner(app, 'valueToSlider', 1);
            CSFGeneratorApp.decode.opticsSubjectIDSpinner(app, 'sliderToValue', 1);
            CSFGeneratorApp.generate.optics(app, []);
            if (app.viewModes.opticsKeepConeMosaicActivationInSync)
                CSFGeneratorApp.compute.coneMosaicActivation(app, []);
                CSFGeneratorApp.render.coneMosaicView(app, 'update');
            end
        end

        % Value changed function: opticsSubjectIDSpinner
        function opticsSubjectIDChanged(app, event)
            value = app.opticsSubjectIDSpinner.Value;
            CSFGeneratorApp.decode.opticsSubjectIDSpinner(app, 'sliderToValue', value);
            CSFGeneratorApp.generate.optics(app, []);
            if (app.viewModes.opticsKeepConeMosaicActivationInSync)
                CSFGeneratorApp.compute.coneMosaicActivation(app, []);
                CSFGeneratorApp.render.coneMosaicView(app, 'update');
            end
        end

        % Value changed function: 
        % opticsWavefrontSpatialSamplesSpinner
        function opticsWavefrontSpatialSamplesSpinnerValueChanged(app, event)
            value = app.opticsWavefrontSpatialSamplesSpinner.Value;
            CSFGeneratorApp.decode.opticsWavefrontSpatialSamplesSpinner(app, 'sliderToValue', value);
            CSFGeneratorApp.generate.optics(app, []);
            if (app.viewModes.opticsKeepConeMosaicActivationInSync)
                CSFGeneratorApp.compute.coneMosaicActivation(app, []);
                CSFGeneratorApp.render.coneMosaicView(app, 'update');
            end
        end

        % Value changed function: opticsPupilSizeSpinner
        function opticsPupilSizeChanged(app, event)
            value = app.opticsPupilSizeSpinner.Value;
            CSFGeneratorApp.decode.opticsPupilSizeSpinner(app, 'sliderToValue', value);
            CSFGeneratorApp.generate.optics(app, []);
            if (app.viewModes.opticsKeepConeMosaicActivationInSync)
                CSFGeneratorApp.compute.coneMosaicActivation(app, []);
                CSFGeneratorApp.render.coneMosaicView(app, 'update');
            end
        end

        % Value changed function: 
        % opticsSubtractCentralRefractionCheckBox
        function opticsSubtractCentralRefractionChanged(app, event)
            value = app.opticsSubtractCentralRefractionCheckBox.Value;
            CSFGeneratorApp.decode.opticsSubtractCentralRefractionCheckBox(app, 'sliderToValue', value);
            CSFGeneratorApp.generate.optics(app, []);
            if (app.viewModes.opticsKeepConeMosaicActivationInSync)
                CSFGeneratorApp.compute.coneMosaicActivation(app, []);
                CSFGeneratorApp.render.coneMosaicView(app, 'update');
            end
        end

        % Value changed function: opticsZeroCenterPSFCheckBox
        function opticsZeroCenterPSFCheckBoxValueChanged(app, event)
            value = app.opticsZeroCenterPSFCheckBox.Value;
            CSFGeneratorApp.decode.opticsZeroCenterPSFCheckBox(app, 'sliderToValue', value);
            CSFGeneratorApp.generate.optics(app, []);
            if (app.viewModes.opticsKeepConeMosaicActivationInSync)
                CSFGeneratorApp.compute.coneMosaicActivation(app, []);
                CSFGeneratorApp.render.coneMosaicView(app, 'update');
            end
        end

        % Value changed function: opticsFlipPSFUpsideDownCheckBox
        function opticsFlipPSFUpsideDownCheckBoxValueChanged(app, event)
            value = app.opticsFlipPSFUpsideDownCheckBox.Value;
            CSFGeneratorApp.decode.opticsFlipPSFUpsideDownCheckBox(app, 'sliderToValue', value);
            CSFGeneratorApp.generate.optics(app, []);
            if (app.viewModes.opticsKeepConeMosaicActivationInSync)
                CSFGeneratorApp.compute.coneMosaicActivation(app, []);
                CSFGeneratorApp.render.coneMosaicView(app, 'update');
            end
        end

        % Value changed function: 
        % opticsKeepConeMosaicActivationInSyncCheckBox
        function opticsKeepConeMosaicActivationInSyncCheckBoxValueChanged(app, event)
            value = app.opticsKeepConeMosaicActivationInSyncCheckBox.Value;
            CSFGeneratorApp.decode.opticsKeepConeMosaicActivationInSyncCheckBox(app, 'sliderToValue', value);
            if (app.viewModes.opticsKeepConeMosaicActivationInSync)
                CSFGeneratorApp.compute.coneMosaicActivation(app, []);
                CSFGeneratorApp.render.coneMosaicView(app, 'update');
            end
        end

        % Value changed function: stimulusPresentationModeDropDown
        function stimulusPresentationModeChanged(app, event)
            value = app.stimulusPresentationModeDropDown.Value;
            CSFGeneratorApp.decode.stimulusPresentationModeDropDown(app, 'sliderToValue', value);
            CSFGeneratorApp.generate.stimulus(app, []);
            CSFGeneratorApp.compute.coneMosaicActivation(app, []);
            CSFGeneratorApp.render.coneMosaicView(app, 'update');
        end

        % Value changed function: stimulusDurationSpinner
        function stimulusDurationChanged(app, event)
            value = app.stimulusDurationSpinner.Value;
            CSFGeneratorApp.decode.stimulusDurationSpinner(app, 'sliderToValue', value);
            CSFGeneratorApp.generate.stimulus(app, []);
            CSFGeneratorApp.compute.coneMosaicActivation(app, []);
            CSFGeneratorApp.render.coneMosaicView(app, 'update');
        end

        % Value changed function: stimulusTemporalFrequencySpinner
        function stimulusTemporalFrequencyChanged(app, event)
            value = app.stimulusTemporalFrequencySpinner.Value;
            CSFGeneratorApp.decode.stimulusTemporalFrequencySpinner(app, 'sliderToValue', value);
            CSFGeneratorApp.generate.stimulus(app);
            CSFGeneratorApp.compute.coneMosaicActivation(app, []);
            CSFGeneratorApp.render.coneMosaicView(app, 'update');
        end

        % Value changed function: stimulusSpatialEnvelopeDropDown
        function stimulusSpatialEnvelopeChanged(app, event)
            value = app.stimulusSpatialEnvelopeDropDown.Value;
            CSFGeneratorApp.decode.stimulusSpatialEnvelopeDropDown(app, 'sliderToValue', value);
            CSFGeneratorApp.generate.stimulus(app, []);
            CSFGeneratorApp.compute.coneMosaicActivation(app, []);
            CSFGeneratorApp.render.coneMosaicView(app, 'update');
        end

        % Value changed function: stimulusOrientationSpinner
        function stimulusOrientationChanged(app, event)
            value = app.stimulusOrientationSpinner.Value;
            CSFGeneratorApp.decode.stimulusOrientationSpinner(app, 'sliderToValue', value);
            CSFGeneratorApp.generate.stimulus(app, []);
            CSFGeneratorApp.compute.coneMosaicActivation(app, []);
            CSFGeneratorApp.render.coneMosaicView(app, 'update');
        end

        % Value changed function: stimulusSpatialFrequencySpinner
        function stimulusSpatialFrequencyChanged(app, event)
            value = app.stimulusSpatialFrequencySpinner.Value;
            CSFGeneratorApp.decode.stimulusSpatialFrequencySpinner(app, 'sliderToValue', value);
            CSFGeneratorApp.generate.stimulus(app, []);
            CSFGeneratorApp.compute.coneMosaicActivation(app, []);
            CSFGeneratorApp.render.coneMosaicView(app, 'update');
        end

        % Value changed function: stimulusSpatialPhaseSpinner
        function stimulusSpatialPhaseSpinnerValueChanged(app, event)
            value = app.stimulusSpatialPhaseSpinner.Value;
            CSFGeneratorApp.decode.stimulusSpatialPhaseSpinner(app, 'sliderToValue', value);
            CSFGeneratorApp.generate.stimulus(app, []);
            CSFGeneratorApp.compute.coneMosaicActivation(app, []);
            CSFGeneratorApp.render.coneMosaicView(app, 'update');
        end

        % Value changed function: stimulusSizeSpinner
        function stimulusSizeChanged(app, event)
            value = app.stimulusSizeSpinner.Value;
            CSFGeneratorApp.decode.stimulusSizeSpinner(app, 'sliderToValue', value);
            CSFGeneratorApp.generate.stimulus(app, []);
            CSFGeneratorApp.compute.coneMosaicActivation(app, []);
            CSFGeneratorApp.render.coneMosaicView(app, 'update');
            CSFGeneratorApp.render.roiView(app, 'update');
        end

        % Value changed function: stimulusResolutionSpinner
        function stimulusResolutionChanged(app, event)
            value = app.stimulusResolutionSpinner.Value;
            CSFGeneratorApp.decode.stimulusResolutionSpinner(app, 'sliderToValue', value);
            CSFGeneratorApp.generate.stimulus(app, []);
            CSFGeneratorApp.compute.coneMosaicActivation(app, []);
            CSFGeneratorApp.render.coneMosaicView(app, 'update');
        end

        % Value changed function: stimulusSpatialPositionXSpinner
        function stimulusSpatialPositionXChanged(app, event)
            value = app.stimulusSpatialPositionXSpinner.Value;
            CSFGeneratorApp.decode.stimulusSpatialPositionXSpinner(app, 'sliderToValue', value);
            CSFGeneratorApp.generate.stimulus(app, []);
            CSFGeneratorApp.compute.coneMosaicActivation(app, []);
            CSFGeneratorApp.render.coneMosaicView(app, 'update');
            CSFGeneratorApp.render.roiView(app, 'update');
        end

        % Value changed function: stimulusSpatialPositionYSpinner
        function stimulusSpatialPositionYChanged(app, event)
            value = app.stimulusSpatialPositionYSpinner.Value;
            CSFGeneratorApp.decode.stimulusSpatialPositionYSpinner(app, 'sliderToValue', value);
            CSFGeneratorApp.generate.stimulus(app, []);
            CSFGeneratorApp.compute.coneMosaicActivation(app, []);
            CSFGeneratorApp.render.coneMosaicView(app, 'update');
            CSFGeneratorApp.render.roiView(app, 'update');
        end

        % Value changed function: stimulusMosaicCenteredCheckBox
        function stimulusMosaicCenteredChanged(app, event)
            value = app.stimulusMosaicCenteredCheckBox.Value;
            CSFGeneratorApp.decode.stimulusMosaicCenteredCheckBox(app, 'sliderToValue', value);
            CSFGeneratorApp.generate.stimulus(app, []);
            CSFGeneratorApp.compute.coneMosaicActivation(app, []);
            CSFGeneratorApp.render.coneMosaicView(app, 'update');
            CSFGeneratorApp.render.roiView(app, 'update');
        end

        % Value changed function: stimulusMeanLuminanceSpinner
        function stimulusMeanLuminanceChanged(app, event)
            value = app.stimulusMeanLuminanceSpinner.Value;
            CSFGeneratorApp.decode.stimulusMeanLuminanceSpinner(app, 'sliderToValue', value);
            CSFGeneratorApp.generate.stimulus(app, []);
            CSFGeneratorApp.compute.coneMosaicActivation(app, []);
            CSFGeneratorApp.render.coneMosaicView(app, 'update');
        end

        % Value changed function: stimulusLconeContrastSpinner
        function stimulusLconeContrastChanged(app, event)
            value = app.stimulusLconeContrastSpinner.Value;
            CSFGeneratorApp.decode.stimulusLconeContrastSpinner(app, 'sliderToValue', value);
        end

        % Value changed function: stimulusMconeContrastSpinner
        function stimulusMconeContrastChanged(app, event)
            value = app.stimulusMconeContrastSpinner.Value;
            CSFGeneratorApp.decode.stimulusMconeContrastSpinner(app, 'sliderToValue', value);
        end

        % Value changed function: stimulusSconeContrastSpinner
        function stimulusSconeContrastChanged(app, event)
            value = app.stimulusSconeContrastSpinner.Value;
            CSFGeneratorApp.decode.stimulusSconeContrastSpinner(app, 'sliderToValue', value);
        end

        % Value changed function: 
        % stimulusWavelengthSupportMinSpinner
        function stimulusWavelengthSupportMinChanged(app, event)
            value = app.stimulusWavelengthSupportMinSpinner.Value;
            CSFGeneratorApp.decode.stimulusWavelengthSupportMinSpinner(app, 'sliderToValue', value);
        end

        % Value changed function: 
        % stimulusWavelengthSupportMaxSpinner
        function stimulusWavelengthSupportMaxChanged(app, event)
            value = app.stimulusWavelengthSupportMaxSpinner.Value;
            CSFGeneratorApp.decode.stimulusWavelengthSupportMaxSpinner(app, 'sliderToValue', value);
        end

        % Value changed function: 
        % stimulusWavelengthSupportStepSizeSpinner
        function stimulusWavelengthSupportStepSizeChanged(app, event)
            value = app.stimulusWavelengthSupportStepSizeSpinner.Value;
            CSFGeneratorApp.decode.stimulusWavelengthSupportStepSizeSpinner(app, 'sliderToValue', value);
        end

        % Button pushed function: stimulusGenerateButton
        function stimulusGenerateButtonPushed(app, event)
            CSFGeneratorApp.generate.stimulus(app, []);
            CSFGeneratorApp.compute.coneMosaicActivation(app, []);
            CSFGeneratorApp.render.coneMosaicView(app, 'update');
        end

        % Selection change function: TabGroupA
        function TabGroupASelectionChanged(app, event)
            selectedTab = app.TabGroupA.SelectedTab;
            % Update status field for this tab
            CSFGeneratorApp.render.statusField(app,'A', selectedTab.Title);            
        end

        % Selection change function: TabGroupB
        function TabGroupBSelectionChanged(app, event)
            selectedTab = app.TabGroupB.SelectedTab;
            % Update status field for this tab
            CSFGeneratorApp.render.statusField(app,'B', selectedTab.Title); 
        end

        % Value changed function: coneMosaicViewModeKnob
        function coneMosaicViewModeKnobValueChanged(app, event)
            value = app.coneMosaicViewModeKnob.Value;
            CSFGeneratorApp.decode.coneMosaicViewModeKnob(app, 'sliderToValue', value);
            CSFGeneratorApp.render.coneMosaicView(app, 'update');
        end

        % Value changed function: coneMosaicActivationTypeSwitch
        function coneMosaicActivationTypeSwitchValueChanged(app, event)
            value = app.coneMosaicActivationTypeSwitch.Value;
            CSFGeneratorApp.decode.coneMosaicActivationTypeSwitch(app, 'sliderToValue', value);
            CSFGeneratorApp.render.coneMosaicView(app, 'update');
        end

        % Value changed function: 
        % coneMosaicVisualizationDomainSwitch
        function coneMosaicVisualizationDomainSwitchValueChanged(app, event)
            value = app.coneMosaicVisualizationDomainSwitch.Value;
            CSFGeneratorApp.decode.coneMosaicVisualizationDomainSwitch(app, 'sliderToValue', value);
            CSFGeneratorApp.render.coneMosaicView(app, 'update');
        end

        % Value changed function: 
        % coneMosaicEccVaryingMacularPigmentDensityCheckBox
        function coneMosaicEccVaryingMacularPigmentDensityCheckBoxValueChanged(app, event)
            value = app.coneMosaicEccVaryingMacularPigmentDensityCheckBox.Value;
            CSFGeneratorApp.decode.coneMosaicEccVaryingMacularPigmentDensityCheckBox(app, 'sliderToValue', value);
            CSFGeneratorApp.generate.processingPipeline(app, 'Generating new processing pipeline', ...
                'doNotRegenerateConeMosaic', true, ...
                'doNotRegenerateOptics', true, ...
                'doNotRegenerateStimulus', true);
            CSFGeneratorApp.render.coneMosaicView(app, 'update');
        end

        % Value changed function: 
        % coneMosaicEccVaryingMacularPigmentDynamicCheckBox
        function coneMosaicEccVaryingMacularPigmentDynamicCheckBoxValueChanged(app, event)
            value = app.coneMosaicEccVaryingMacularPigmentDynamicCheckBox.Value;
            CSFGeneratorApp.decode.coneMosaicEccVaryingMacularPigmentDynamicCheckBox(app, 'sliderToValue', value);
            CSFGeneratorApp.generate.processingPipeline(app, 'Generating new processing pipeline', ...
                'doNotRegenerateConeMosaic', true, ...
                'doNotRegenerateOptics', true, ...
                'doNotRegenerateStimulus', true);
            CSFGeneratorApp.render.coneMosaicView(app, 'update');
        end

        % Value changed function: 
        % coneMosaicEccVaryingConeApertureCheckBox
        function coneMosaicEccVaryingConeApertureCheckBoxValueChanged(app, event)
            value = app.coneMosaicEccVaryingConeApertureCheckBox.Value;
            CSFGeneratorApp.decode.coneMosaicEccVaryingConeApertureCheckBox(app, 'sliderToValue', value);
            CSFGeneratorApp.generate.processingPipeline(app, 'Generating new processing pipeline', ...
                'doNotRegenerateConeMosaic', true, ...
                'doNotRegenerateOptics', true, ...
                'doNotRegenerateStimulus', true);
            CSFGeneratorApp.render.coneMosaicView(app, 'update');
        end

        % Value changed function: 
        % coneMosaicEccVaryingConeApertureBlurCheckBox
        function coneMosaicEccVaryingConeApertureBlurCheckBoxValueChanged(app, event)
            value = app.coneMosaicEccVaryingConeApertureBlurCheckBox.Value;
            CSFGeneratorApp.decode.coneMosaicEccVaryingConeApertureBlurCheckBox(app, 'sliderToValue', value);
            CSFGeneratorApp.generate.processingPipeline(app, 'Generating new processing pipeline', ...
                'doNotRegenerateConeMosaic', true, ...
                'doNotRegenerateOptics', true, ...
                'doNotRegenerateStimulus', true);
            CSFGeneratorApp.render.coneMosaicView(app, 'update');
        end

        % Value changed function: 
        % coneMosaicEccVaryingOuterSegmentLengthCheckBox
        function coneMosaicEccVaryingOuterSegmentLengthCheckBoxValueChanged(app, event)
            value = app.coneMosaicEccVaryingOuterSegmentLengthCheckBox.Value;
            CSFGeneratorApp.decode.coneMosaicEccVaryingOuterSegmentLengthCheckBox(app, 'sliderToValue', value);
            CSFGeneratorApp.generate.processingPipeline(app, 'Generating new processing pipeline', ...
                'doNotRegenerateConeMosaic', true, ...
                'doNotRegenerateOptics', true, ...
                'doNotRegenerateStimulus', true);
            CSFGeneratorApp.render.coneMosaicView(app, 'update');
        end

        % Value changed function: coneMosaicIntegrationTimeSpinner
        function coneMosaicIntegrationTimeSpinnerValueChanged(app, event)
            value = app.coneMosaicIntegrationTimeSpinner.Value;
            CSFGeneratorApp.decode.coneMosaicIntegrationTimeSpinner(app, 'sliderToValue', value);
            CSFGeneratorApp.generate.processingPipeline(app, 'Generating new processing pipeline', ...
                'doNotRegenerateConeMosaic', true, ...
                'doNotRegenerateOptics', true, ...
                'doNotRegenerateStimulus', true);
            CSFGeneratorApp.render.coneMosaicView(app, 'update');
        end

        % Value changed function: coneMosaicLconeRatioSpinner
        function coneMosaicLconeRatioSpinnerValueChanged(app, event)
            value = app.coneMosaicLconeRatioSpinner.Value;
            CSFGeneratorApp.decode.coneMosaicLconeRatioSpinner(app, 'sliderToValue', value);
        end

        % Value changed function: coneMosaicMconeRatioSpinner
        function coneMosaicMconeRatioSpinnerValueChanged(app, event)
            value = app.coneMosaicMconeRatioSpinner.Value;
            CSFGeneratorApp.decode.coneMosaicMconeRatioSpinner(app, 'sliderToValue', value);
        end

        % Value changed function: coneMosaicSconeRatioSpinner
        function coneMosaicSconeRatioSpinnerValueChanged(app, event)
            value = app.coneMosaicSconeRatioSpinner.Value;
            CSFGeneratorApp.decode.coneMosaicSconeRatioSpinner(app, 'sliderToValue', value);
        end

        % Value changed function: coneMosaicTritanopicRadiusSpinner
        function coneMosaicTritanopicRadiusSpinnerValueChanged(app, event)
            value = app.coneMosaicTritanopicRadiusSpinner.Value;
            CSFGeneratorApp.decode.coneMosaicTritanopicRadiusSpinner(app, 'sliderToValue', value);
            CSFGeneratorApp.generate.processingPipeline(app, 'Generating new processing pipeline', ...
                'doNotRegenerateConeMosaic', true, ...
                'doNotRegenerateOptics', true, ...
                'doNotRegenerateStimulus', true);
            CSFGeneratorApp.render.coneMosaicView(app, 'update');
        end

        % Button pushed function: coneMosaicRegenerateButton
        function coneMosaicReassignConeIdentitiesButtonPushed(app, event)
            % This will trigger a new cone reassignment
            coneDensities = [app.coneMosaicParams.lConeRatio  app.coneMosaicParams.mConeRatio  app.coneMosaicParams.sConeRatio];
            coneDensities = coneDensities/sum(coneDensities);
            if (coneDensities(1) == app.components.coneMosaic.coneDensities(1)) || ...
               (coneDensities(2) == app.components.coneMosaic.coneDensities(2)) || ...
               (coneDensities(3) == app.components.coneMosaic.coneDensities(3))
                        % change one cone density slightly, and set the
                        % coneDensities property, which triggers a
                        % re-assignment of cone types
                        coneDensities(3) = app.components.coneMosaic.coneDensities(3) + 0.001*randn(1,1);
            end
            app.components.coneMosaic.coneDensities = coneDensities;
            
            CSFGeneratorApp.generate.processingPipeline(app, 'Generating new processing pipeline', ...
                'doNotRegenerateConeMosaic', true, ...
                'doNotRegenerateOptics', true, ...
                'doNotRegenerateStimulus', true);
            CSFGeneratorApp.render.coneMosaicView(app, 'update');
        end

        % Value changed function: coneMosaicActivationSignalSwitch
        function coneMosaicActivationSignalSwitchValueChanged(app, event)
            value = app.coneMosaicActivationSignalSwitch.Value;
            CSFGeneratorApp.decode.coneMosaicActivationSignalSwitch(app, 'sliderToValue', value);
        end

        % Value changed function: 
        % coneMosaicActivationDimensionalitySwitch
        function coneMosaicActivationDimensionalitySwitchValueChanged(app, event)
            value = app.coneMosaicActivationDimensionalitySwitch.Value;
            CSFGeneratorApp.decode.coneMosaicDimensionalitySwitch(app, 'sliderToValue', value);
        end

        % Value changed function: 
        % psychometricFunctionClassifierTypeDropDown
        function psychometricFunctionClassifierTypeDropDownValueChanged(app, event)
            value = app.psychometricFunctionClassifierTypeDropDown.Value;
            CSFGeneratorApp.decode.psychometricFunctionClassifierTypeDropDown(app, 'sliderToValue', value);
        end

        % Value changed function: 
        % psychometricFunctionClassifierTrainingTrialsSpinner
        function psychometricFunctionClassifierTrainingTrialsSpinnerValueChanged(app, event)
            value = app.psychometricFunctionClassifierTrainingTrialsSpinner.Value;
            CSFGeneratorApp.decode.psychometricFunctionClassifierTrainingTrialsSpinner(app, 'sliderToValue', value);
        end

        % Value changed function: 
        % psychometricFunctionClassifierTestTrialsSpinner
        function psychometricFunctionClassifierTestTrialsSpinnerValueChanged(app, event)
            value = app.psychometricFunctionClassifierTestTrialsSpinner.Value;
            CSFGeneratorApp.decode.psychometricFunctionClassifierTestTrialsSpinner(app, 'sliderToValue', value);
        end

        % Value changed function: 
        % psychometricFunctionContrastLevelsSpinner
        function psychometricFunctionContrastLevelsSpinnerValueChanged(app, event)
            value = app.psychometricFunctionContrastLevelsSpinner.Value;
            CSFGeneratorApp.decode.psychometricFunctionContrastLevelsSpinner(app, 'sliderToValue', value);
        end

        % Value changed function: 
        % psychometricFunctionLog10ContrastMinSpinner
        function psychometricFunctionLog10ContrastMinSpinnerValueChanged(app, event)
            value = app.psychometricFunctionLog10ContrastMinSpinner.Value;
            CSFGeneratorApp.decode.psychometricFunctionLog10ContrastMinSpinner(app, 'sliderToValue', value);
        end

        % Value changed function: 
        % psychometricFunctionLog10ContrastMaxSpinner
        function psychometricFunctionLog10ContrastMaxSpinnerValueChanged(app, event)
            value = app.psychometricFunctionLog10ContrastMaxSpinner.Value;
            CSFGeneratorApp.decode.psychometricFunctionLog10ContrastMaxSpinner(app, 'sliderToValue', value);
        end

        % Value changed function: 
        % psychometricFunctionLog10ContrastDeltaSpinner
        function psychometricFunctionLog10ContrastDeltaSpinnerValueChanged(app, event)
            value = app.psychometricFunctionLog10ContrastDeltaSpinner.Value;
            CSFGeneratorApp.decode.psychometricFunctionLog10ContrastDeltaSpinner(app, 'sliderToValue', value);
        end

        % Value changed function: 
        % psychometricFunctionSlopeMinSpinner
        function psychometricFunctionSlopeMinSpinnerValueChanged(app, event)
            value = app.psychometricFunctionSlopeMinSpinner.Value;
            CSFGeneratorApp.decode.psychometricFunctionSlopeMinSpinner(app, 'sliderToValue', value);
        end

        % Value changed function: 
        % psychometricFunctionSlopeMaxSpinner
        function psychometricFunctionSlopeMaxSpinnerValueChanged(app, event)
            value = app.psychometricFunctionSlopeMaxSpinner.Value;
            CSFGeneratorApp.decode.psychometricFunctionSlopeMaxSpinner(app, 'sliderToValue', value);
        end

        % Value changed function: 
        % psychometricFunctionSlopeDeltaSpinner
        function psychometricFunctionSlopeDeltaSpinnerValueChanged(app, event)
            value = app.psychometricFunctionSlopeDeltaSpinner.Value;
            CSFGeneratorApp.decode.psychometricFunctionSlopeDeltaSpinner(app, 'sliderToValue', value);
        end

        % Value changed function: 
        % psychometricFunctionEstimationMethodSwitch
        function psychometricFunctionEstimationMethodSwitchValueChanged(app, event)
            value = app.psychometricFunctionEstimationMethodSwitch.Value;
            CSFGeneratorApp.decode.psychometricFunctionEstimationMethodSwitch(app, 'sliderToValue', value);
        end

        % Value changed function: csfSpatialFrequencyMinSpinner
        function csfSpatialFrequencyMinSpinnerValueChanged(app, event)
            value = app.csfSpatialFrequencyMinSpinner.Value;
            CSFGeneratorApp.decode.csfSpatialFrequencyMinSpinner(app, 'sliderToValue', value);
        end

        % Value changed function: csfSpatialFrequencyMaxSpinner
        function csfSpatialFrequencyMaxSpinnerValueChanged(app, event)
            value = app.csfSpatialFrequencyMaxSpinner.Value;
            CSFGeneratorApp.decode.csfSpatialFrequencyMaxSpinner(app, 'sliderToValue', value);
        end

        % Value changed function: csfSpatialFrequencySamplesSpinner
        function csfSpatialFrequencySamplesSpinnerValueChanged(app, event)
            value = app.csfSpatialFrequencySamplesSpinner.Value;
            CSFGeneratorApp.decode.csfSpatialFrequencySamplesSpinner(app, 'sliderToValue', value);
        end

        % Value changed function: csfConstantParameterSwitch
        function csfConstantParameterSwitchValueChanged(app, event)
            value = app.csfConstantParameterSwitch.Value;
            CSFGeneratorApp.decode.csfConstantParameterSwitch(app, 'sliderToValue', value);
            CSFGeneratorApp.generate.stimulus(app, []);
            CSFGeneratorApp.compute.coneMosaicActivation(app, []);
            CSFGeneratorApp.render.coneMosaicView(app, 'update');
        end

        % Value changed function: csfNumberOfConstantCyclesSpinner
        function csfNumberOfConstantCyclesSpinnerValueChanged(app, event)
            value = app.csfNumberOfConstantCyclesSpinner.Value;
            CSFGeneratorApp.decode.csfNumberOfConstantCyclesSpinner(app, 'sliderToValue', value);
            CSFGeneratorApp.generate.stimulus(app, []);
            CSFGeneratorApp.compute.coneMosaicActivation(app, []);
            CSFGeneratorApp.render.coneMosaicView(app, 'update');
        end

        % Value changed function: csfSourceSignalKnob
        function csfSourceSignalKnobValueChanged(app, event)
            value = app.csfSourceSignalKnob.Value;
            CSFGeneratorApp.decode.csfSourceSignalKnob(app, 'sliderToValue', value);
        end

        % Value changed function: csfRunButton
        function csfRunButtonValueChanged(app, event)
            value = app.csfRunButton.Value;
            if (value == 1)
                 % Reset the toggle button state
                 app.csfRunButton.Enable = 'off';
    
                % Reset graphics
                CSFGeneratorApp.render.psychometricFunctionView(app, 'initialize');
                CSFGeneratorApp.render.psychometricFunctionView(app, 'update'); 
                CSFGeneratorApp.render.csfView(app, 'initialize');
                CSFGeneratorApp.render.csfView(app, 'update'); 
                pause(0.1);
                app.products.contrastSensitivityFunction = CSFGeneratorApp.compute.contrastSensitivityFunction(app);
                
                % Reset the toggle button state
                app.csfRunButton.Enable = 'on';
                app.csfRunButton.Value = false;
            end
            
        end

        % Button pushed function: resetCSFRunButton
        function resetCSFRunButtonPushed(app, event)
            app.resetButtonPressed = true;
            app.csfRunButton.Enable = 'on';
            app.csfRunButton.Value = false;
        end

        % Button pushed function: exportPipelineButton
        function exportPipelineButtonPushed(app, event)
            % Ask the use what data to export
            optionLabels = {'compute pipeline'; ...
                            'compute pipeline & CSF data'; ...
                            'cancel'};
            defaultOptionID = 1;
            cancelOptionID = 3;
            msg = 'select a dataset to export'; title = 'data to export';
            exportedDataSelection = uiconfirm(app.mainView, msg, title, ...
                 'Options', optionLabels, ...
                 'DefaultOption', defaultOptionID, ...
                 'CancelOption', cancelOptionID);
            
            % Hide main app
            app.mainView.Visible = 'off';
             
            % Ask uset for exportFileName and directory
            [fileName, directoryName] = uiputfile('*.mat','Select where to export the data', fullfile(app.exportDirectoryName,'CSFGeneratorAppExport.mat'));
            
            if (fileName ~= 0)  
                % Get the exportFileName
                app.exportDirectoryName = directoryName;
                if (contains(fileName, '.mat'))
                    fileName = strrep(fileName, '.mat', '');
                end
                
                % Export the data
                exportFileName = sprintf('%s.mat', fullfile(app.exportDirectoryName, fileName));
                CSFGeneratorApp.export.paramsForBatchProcessing(app, exportFileName, exportedDataSelection);
                
                % Show the app again
                app.mainView.Visible = 'on';
            
                % Let the user know
                message = sprintf('Saved in %s.', exportFileName);
                
                % Some feecback
                app.statusMessages('tasks')  = struct(...
                   'text', message,...
                   'fontColor', app.colors('good message foreground'), ...
                   'backgroundColor', app.colors('good message background'), ...
                   'fontWeight', 'normal');

                % Render the status on the status field of tab B
                CSFGeneratorApp.render.statusField(app,'A', 'tasks'); 
            end
            
            % Show the app again
            app.mainView.Visible = 'on';
            
        end

        % Button pushed function: exportCSFPDF
        function exportCSFPDFPushed(app, event)
            CSFGeneratorApp.render.csfPDF(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.mainView = uifigure('Visible', 'off');
            app.mainView.Name = 'ISETBio CSF Generator              Developed by N.P. Cottaris                ISETBio Team, 2021';
            app.GridLayout = uigridlayout(app.mainView);
            app.TabGroupB = uitabgroup(app.GridLayout);
            app.TabGroupB.SelectionChangedFcn = createCallbackFcn(app, @TabGroupBSelectionChanged, true);
            app.opticsTab = uitab(app.TabGroupB);
            app.opticsTab.Title = 'optics';
            app.Panel_3 = uipanel(app.opticsTab);
            app.subjectIDSpinnerLabel = uilabel(app.Panel_3);
            app.subjectIDSpinnerLabel.Text = 'subject ID ';
            app.opticsSubjectIDSpinner = uispinner(app.Panel_3);
            app.opticsSubjectIDSpinner.Limits = [1 10];
            app.opticsSubjectIDSpinner.ValueChangedFcn = createCallbackFcn(app, @opticsSubjectIDChanged, true);
            app.opticsSubjectIDSpinner.Value = 10;
            app.subjectdatasetLabel = uilabel(app.Panel_3);
            app.subjectdatasetLabel.Text = 'subject dataset ';
            app.opticsSubjectDataBaseDropDown = uidropdown(app.Panel_3);
            app.opticsSubjectDataBaseDropDown.Items = {'Polans2015'};
            app.opticsSubjectDataBaseDropDown.ValueChangedFcn = createCallbackFcn(app, @opticsSubjectDataBaseChanged, true);
            app.opticsSubjectDataBaseDropDown.Tooltip = {'The Polans dataset contains wavefront aberration measurements along both the horizontal and vertical axes, but data exist only for the right eye. The Artal dataset contains measurements along the horizontal axis only, but data exist for both eyes.'};
            app.opticsSubjectDataBaseDropDown.Value = 'Polans2015';
            app.pupilsizemmLabel = uilabel(app.Panel_3);
            app.pupilsizemmLabel.Text = 'pupil size (mm) ';
            app.opticsPupilSizeSpinner = uispinner(app.Panel_3);
            app.opticsPupilSizeSpinner.Step = 0.5;
            app.opticsPupilSizeSpinner.Limits = [1 4];
            app.opticsPupilSizeSpinner.ValueChangedFcn = createCallbackFcn(app, @opticsPupilSizeChanged, true);
            app.opticsPupilSizeSpinner.Value = 3;
            app.opticsSubtractCentralRefractionCheckBox = uicheckbox(app.Panel_3);
            app.opticsSubtractCentralRefractionCheckBox.ValueChangedFcn = createCallbackFcn(app, @opticsSubtractCentralRefractionChanged, true);
            app.opticsSubtractCentralRefractionCheckBox.Tooltip = {'Enabling this subtracts the defocus coefficient (Z4) measured at the fovea, from the defocus coefficients at all positions'};
            app.opticsSubtractCentralRefractionCheckBox.Text = ' subtract central refraction';
            app.xpositionsLabel = uilabel(app.Panel_3);
            app.xpositionsLabel.Enable = 'off';
            app.xpositionsLabel.Text = 'x-positions';
            app.opticsPSFGridXPositionsSpinner = uispinner(app.Panel_3);
            app.opticsPSFGridXPositionsSpinner.Limits = [1 10];
            app.opticsPSFGridXPositionsSpinner.ValueChangedFcn = createCallbackFcn(app, @opticsPSFGridXPositionsChanged, true);
            app.opticsPSFGridXPositionsSpinner.Enable = 'off';
            app.opticsPSFGridXPositionsSpinner.Tooltip = {'PSF samples along the horizontal axis'};
            app.opticsPSFGridXPositionsSpinner.Value = 3;
            app.ypositionsLabel = uilabel(app.Panel_3);
            app.ypositionsLabel.Enable = 'off';
            app.ypositionsLabel.Text = 'y-positions';
            app.opticsPSFGridYPositionsSpinner = uispinner(app.Panel_3);
            app.opticsPSFGridYPositionsSpinner.Limits = [1 10];
            app.opticsPSFGridYPositionsSpinner.ValueChangedFcn = createCallbackFcn(app, @opticsPSFGridYPositionsChanged, true);
            app.opticsPSFGridYPositionsSpinner.Enable = 'off';
            app.opticsPSFGridYPositionsSpinner.Tooltip = {'PSF samples along the vertical axis'};
            app.opticsPSFGridYPositionsSpinner.Value = 3;
            app.PSFspatialgridLabel = uilabel(app.Panel_3);
            app.PSFspatialgridLabel.Text = 'PSF spatial grid';
            app.opticsVisualizedWavelengthSlider = uislider(app.Panel_3);
            app.opticsVisualizedWavelengthSlider.Limits = [400 700];
            app.opticsVisualizedWavelengthSlider.MajorTicks = [400 450 500 550 600 650 700];
            app.opticsVisualizedWavelengthSlider.MajorTickLabels = {'400', '450', '500', '550', '600', '650', '700'};
            app.opticsVisualizedWavelengthSlider.ValueChangedFcn = createCallbackFcn(app, @opticsVisualizedWavelengthSliderChanged, true);
            app.opticsVisualizedWavelengthSlider.ValueChangingFcn = createCallbackFcn(app, @opticsWavelengthSliderChanging, true);
            app.opticsVisualizedWavelengthSlider.Value = 550;
            app.wavelengthnmSliderLabel = uilabel(app.Panel_3);
            app.wavelengthnmSliderLabel.Text = 'PSF visualization wavelength (nm)';
            app.opticsKeepConeMosaicActivationInSyncCheckBox = uicheckbox(app.Panel_3);
            app.opticsKeepConeMosaicActivationInSyncCheckBox.ValueChangedFcn = createCallbackFcn(app, @opticsKeepConeMosaicActivationInSyncCheckBoxValueChanged, true);
            app.opticsKeepConeMosaicActivationInSyncCheckBox.Tooltip = {'Enabling this ensures that the visualized cone mosaic activation reflects changes in the optics. Disable for faster visualization of optics.'};
            app.opticsKeepConeMosaicActivationInSyncCheckBox.Text = ' keep cone mosaic activation in sync with changes in optics';
            app.wavefrontspatialsamplesLabel = uilabel(app.Panel_3);
            app.wavefrontspatialsamplesLabel.Text = 'wavefront spatial samples ';
            app.opticsWavefrontSpatialSamplesSpinner = uispinner(app.Panel_3);
            app.opticsWavefrontSpatialSamplesSpinner.Step = 100;
            app.opticsWavefrontSpatialSamplesSpinner.Limits = [301 1001];
            app.opticsWavefrontSpatialSamplesSpinner.ValueChangedFcn = createCallbackFcn(app, @opticsWavefrontSpatialSamplesSpinnerValueChanged, true);
            app.opticsWavefrontSpatialSamplesSpinner.Tooltip = {'Some PSFs may become too large, especially toward the periphery, resulting in a wrap around. Increasing the spatial samples eliminates this artifact. but increases computation time.'};
            app.opticsWavefrontSpatialSamplesSpinner.Value = 301;
            app.opticsZeroCenterPSFCheckBox = uicheckbox(app.Panel_3);
            app.opticsZeroCenterPSFCheckBox.ValueChangedFcn = createCallbackFcn(app, @opticsZeroCenterPSFCheckBoxValueChanged, true);
            app.opticsZeroCenterPSFCheckBox.Tooltip = {'Enabling this places the center of gravity of the PSF on the origin of its spatial support.'};
            app.opticsZeroCenterPSFCheckBox.Text = ' zero-center PSF';
            app.opticsFlipPSFUpsideDownCheckBox = uicheckbox(app.Panel_3);
            app.opticsFlipPSFUpsideDownCheckBox.ValueChangedFcn = createCallbackFcn(app, @opticsFlipPSFUpsideDownCheckBoxValueChanged, true);
            app.opticsFlipPSFUpsideDownCheckBox.Tooltip = {'Enabling this, flips the PSF left/right.'};
            app.opticsFlipPSFUpsideDownCheckBox.Text = 'flip PSF';
            app.opticsView = uiaxes(app.opticsTab);
            xlabel(app.opticsView, 'X')
            zlabel(app.opticsView, 'Z')
            app.opticsView.YTick = [];
            app.opticsView.YTickLabel = '';
            app.opticsViewPSFGrid = uiaxes(app.opticsTab);
            zlabel(app.opticsViewPSFGrid, 'Z')
            app.opticsViewPSFGrid.XTick = [];
            app.opticsViewPSFGrid.XTickLabel = '';
            app.opticsViewPSFGrid.YTick = [];
            app.opticsViewPSFGrid.YTickLabel = '';
            app.coneMosaicTab = uitab(app.TabGroupB);
            app.coneMosaicTab.Title = 'cone mosaic';
            app.Panel_4 = uipanel(app.coneMosaicTab);
            app.coneMosaicViewModeKnob = uiknob(app.Panel_4, 'discrete');
            app.coneMosaicViewModeKnob.Items = {'cone types', 'cones+retinal image', 'activation', 'modulation', 'residual'};
            app.coneMosaicViewModeKnob.ValueChangedFcn = createCallbackFcn(app, @coneMosaicViewModeKnobValueChanged, true);
            app.coneMosaicViewModeKnob.Tooltip = {'Selects which aspect of the cone mosaic and its activation is visualized'};
            app.coneMosaicViewModeKnob.Value = 'cone types';
            app.coneMosaicActivationTypeSwitch = uiswitch(app.Panel_4, 'slider');
            app.coneMosaicActivationTypeSwitch.Items = {'noise-free responses', 'noisy instances'};
            app.coneMosaicActivationTypeSwitch.ValueChangedFcn = createCallbackFcn(app, @coneMosaicActivationTypeSwitchValueChanged, true);
            app.coneMosaicActivationTypeSwitch.Tooltip = {'Visualize noise-free responses or noisy response instances'};
            app.coneMosaicActivationTypeSwitch.Value = 'noise-free responses';
            app.coneMosaicVisualizationDomainSwitch = uiswitch(app.Panel_4, 'slider');
            app.coneMosaicVisualizationDomainSwitch.Items = {'degrees', 'microns'};
            app.coneMosaicVisualizationDomainSwitch.ValueChangedFcn = createCallbackFcn(app, @coneMosaicVisualizationDomainSwitchValueChanged, true);
            app.coneMosaicVisualizationDomainSwitch.Value = 'degrees';
            app.LSpinnerLabel = uilabel(app.Panel_4);
            app.LSpinnerLabel.Text = 'L';
            app.coneMosaicLconeRatioSpinner = uispinner(app.Panel_4);
            app.coneMosaicLconeRatioSpinner.Step = 0.01;
            app.coneMosaicLconeRatioSpinner.Limits = [0 1];
            app.coneMosaicLconeRatioSpinner.ValueChangedFcn = createCallbackFcn(app, @coneMosaicLconeRatioSpinnerValueChanged, true);
            app.coneMosaicLconeRatioSpinner.Tooltip = {'Ratio of L cones to all cones in the mosaic'};
            app.coneMosaicLconeRatioSpinner.Value = 0.6;
            app.MLabel = uilabel(app.Panel_4);
            app.MLabel.Text = 'M';
            app.coneMosaicMconeRatioSpinner = uispinner(app.Panel_4);
            app.coneMosaicMconeRatioSpinner.Step = 0.01;
            app.coneMosaicMconeRatioSpinner.Limits = [0 1];
            app.coneMosaicMconeRatioSpinner.ValueChangedFcn = createCallbackFcn(app, @coneMosaicMconeRatioSpinnerValueChanged, true);
            app.coneMosaicMconeRatioSpinner.Tooltip = {'Ratio of M cones to all cones in the mosaic'};
            app.coneMosaicMconeRatioSpinner.Value = 0.33;
            app.SLabel = uilabel(app.Panel_4);
            app.SLabel.Text = 'S';
            app.coneMosaicSconeRatioSpinner = uispinner(app.Panel_4);
            app.coneMosaicSconeRatioSpinner.Step = 0.01;
            app.coneMosaicSconeRatioSpinner.Limits = [0 1];
            app.coneMosaicSconeRatioSpinner.ValueChangedFcn = createCallbackFcn(app, @coneMosaicSconeRatioSpinnerValueChanged, true);
            app.coneMosaicSconeRatioSpinner.Tooltip = {'Ratio of S cones to all cones in the mosaic'};
            app.coneMosaicSconeRatioSpinner.Value = 0.07;
            app.coneratiosLabel = uilabel(app.Panel_4);
            app.coneratiosLabel.Text = 'cone ratios';
            app.coneMosaicRegenerateButton = uibutton(app.Panel_4, 'push');
            app.coneMosaicRegenerateButton.ButtonPushedFcn = createCallbackFcn(app, @coneMosaicReassignConeIdentitiesButtonPushed, true);
            app.coneMosaicRegenerateButton.Tooltip = {'Regenerate cone mosaic with new L,M,S ratios'};
            app.coneMosaicRegenerateButton.Text = 'reassign cone identities';
            app.tritanopicradiusdegreesLabel = uilabel(app.Panel_4);
            app.tritanopicradiusdegreesLabel.Text = 'tritanopic radius (degrees) ';
            app.coneMosaicTritanopicRadiusSpinner = uispinner(app.Panel_4);
            app.coneMosaicTritanopicRadiusSpinner.Step = 0.01;
            app.coneMosaicTritanopicRadiusSpinner.Limits = [0 1];
            app.coneMosaicTritanopicRadiusSpinner.ValueChangedFcn = createCallbackFcn(app, @coneMosaicTritanopicRadiusSpinnerValueChanged, true);
            app.coneMosaicTritanopicRadiusSpinner.Value = 0.15;
            app.integrationtimemsecLabel = uilabel(app.Panel_4);
            app.integrationtimemsecLabel.Text = 'integration time (msec) ';
            app.coneMosaicIntegrationTimeSpinner = uispinner(app.Panel_4);
            app.coneMosaicIntegrationTimeSpinner.Step = 5;
            app.coneMosaicIntegrationTimeSpinner.Limits = [0 1000];
            app.coneMosaicIntegrationTimeSpinner.ValueChangedFcn = createCallbackFcn(app, @coneMosaicIntegrationTimeSpinnerValueChanged, true);
            app.coneMosaicIntegrationTimeSpinner.Tooltip = {'Photon integration time (this is also the temporal resolution of fixational eye movements)'};
            app.coneMosaicIntegrationTimeSpinner.Value = 5;
            app.coneMosaicEccVaryingMacularPigmentDensityCheckBox = uicheckbox(app.Panel_4);
            app.coneMosaicEccVaryingMacularPigmentDensityCheckBox.ValueChangedFcn = createCallbackFcn(app, @coneMosaicEccVaryingMacularPigmentDensityCheckBoxValueChanged, true);
            app.coneMosaicEccVaryingMacularPigmentDensityCheckBox.Tooltip = {'Allow the macular pigment density to vary with eccentricity.'};
            app.coneMosaicEccVaryingMacularPigmentDensityCheckBox.Text = 'eccentricity-varying macular pigment';
            app.coneMosaicEccVaryingMacularPigmentDensityCheckBox.Value = true;
            app.coneMosaicEccVaryingConeApertureCheckBox = uicheckbox(app.Panel_4);
            app.coneMosaicEccVaryingConeApertureCheckBox.ValueChangedFcn = createCallbackFcn(app, @coneMosaicEccVaryingConeApertureCheckBoxValueChanged, true);
            app.coneMosaicEccVaryingConeApertureCheckBox.Tooltip = {'Allow the cone aperture diameter to vary with eccentricity.'};
            app.coneMosaicEccVaryingConeApertureCheckBox.Text = 'eccentricity-varying cone aperture (light gathering)';
            app.coneMosaicEccVaryingConeApertureCheckBox.Value = true;
            app.coneMosaicEccVaryingConeApertureBlurCheckBox = uicheckbox(app.Panel_4);
            app.coneMosaicEccVaryingConeApertureBlurCheckBox.ValueChangedFcn = createCallbackFcn(app, @coneMosaicEccVaryingConeApertureBlurCheckBoxValueChanged, true);
            app.coneMosaicEccVaryingConeApertureBlurCheckBox.Tooltip = {'Allow blurring by the cone aperture to vary with eccentricity.'};
            app.coneMosaicEccVaryingConeApertureBlurCheckBox.Text = 'eccentricity-varying cone aperture (blur)';
            app.coneMosaicEccVaryingOuterSegmentLengthCheckBox = uicheckbox(app.Panel_4);
            app.coneMosaicEccVaryingOuterSegmentLengthCheckBox.ValueChangedFcn = createCallbackFcn(app, @coneMosaicEccVaryingOuterSegmentLengthCheckBoxValueChanged, true);
            app.coneMosaicEccVaryingOuterSegmentLengthCheckBox.Tooltip = {'Allow the outer segment length to vary with eccentricity.'};
            app.coneMosaicEccVaryingOuterSegmentLengthCheckBox.Text = 'eccentricity-varying outer segment length';
            app.coneMosaicEccVaryingOuterSegmentLengthCheckBox.Value = true;
            app.coneMosaicEccVaryingMacularPigmentDynamicCheckBox = uicheckbox(app.Panel_4);
            app.coneMosaicEccVaryingMacularPigmentDynamicCheckBox.ValueChangedFcn = createCallbackFcn(app, @coneMosaicEccVaryingMacularPigmentDynamicCheckBoxValueChanged, true);
            app.coneMosaicEccVaryingMacularPigmentDynamicCheckBox.Tooltip = {'Account for eye movements when computing the macular pigment density at different eccentricities.'};
            app.coneMosaicEccVaryingMacularPigmentDynamicCheckBox.Text = 'eccentricity-varying macular pigment (dynamic)';
            app.coneMosaicActivationSignalSwitch = uiswitch(app.Panel_4, 'slider');
            app.coneMosaicActivationSignalSwitch.Items = {'photocurrents', 'excitations'};
            app.coneMosaicActivationSignalSwitch.ValueChangedFcn = createCallbackFcn(app, @coneMosaicActivationSignalSwitchValueChanged, true);
            app.coneMosaicActivationSignalSwitch.Enable = 'off';
            app.coneMosaicActivationSignalSwitch.Tooltip = {'Visualize cone excitations (isomerizations) or outer segment photocurrents'};
            app.coneMosaicActivationSignalSwitch.Value = 'excitations';
            app.coneMosaicActivationDimensionalitySwitch = uiswitch(app.Panel_4, 'slider');
            app.coneMosaicActivationDimensionalitySwitch.Items = {'space-time', '2D space'};
            app.coneMosaicActivationDimensionalitySwitch.ValueChangedFcn = createCallbackFcn(app, @coneMosaicActivationDimensionalitySwitchValueChanged, true);
            app.coneMosaicActivationDimensionalitySwitch.Enable = 'off';
            app.coneMosaicActivationDimensionalitySwitch.Tooltip = {'Visualize 2D mosaic activation or space-time activation traces'};
            app.coneMosaicActivationDimensionalitySwitch.Value = '2D space';
            app.cpcLabel = uilabel(app.Panel_4);
            app.cpcLabel.Text = '(c-p)/c';
            app.coneMosaicView = uiaxes(app.coneMosaicTab);
            xlabel(app.coneMosaicView, 'X')
            zlabel(app.coneMosaicView, 'Z')
            app.fixationalEyeMovementsTab = uitab(app.TabGroupB);
            app.fixationalEyeMovementsTab.Title = 'fixational eye movements';
            app.Panel_6 = uipanel(app.fixationalEyeMovementsTab);
            app.comingsoonLabel_3 = uilabel(app.fixationalEyeMovementsTab);
            app.comingsoonLabel_3.Text = 'coming soon ...';
            app.mRGCmosaicTab = uitab(app.TabGroupB);
            app.mRGCmosaicTab.Title = 'mRGC mosaic';
            app.Panel_8 = uipanel(app.mRGCmosaicTab);
            app.comingsoonLabel_2 = uilabel(app.mRGCmosaicTab);
            app.comingsoonLabel_2.Text = 'coming soon ...';
            app.pRGCmosaicTab = uitab(app.TabGroupB);
            app.pRGCmosaicTab.Title = 'pRGC mosaic';
            app.Panel_9 = uipanel(app.pRGCmosaicTab);
            app.cominglaterLabel = uilabel(app.pRGCmosaicTab);
            app.cominglaterLabel.Text = 'coming later ...';
            app.computationalObserverTab = uitab(app.TabGroupB);
            app.computationalObserverTab.Title = 'computational observer';
            app.Panel_11 = uipanel(app.computationalObserverTab);
            app.log10contrastminLabel = uilabel(app.Panel_11);
            app.log10contrastminLabel.Text = 'log10 contrast, min ';
            app.psychometricFunctionLog10ContrastMinSpinner = uispinner(app.Panel_11);
            app.psychometricFunctionLog10ContrastMinSpinner.Step = 0.25;
            app.psychometricFunctionLog10ContrastMinSpinner.Limits = [-6 0];
            app.psychometricFunctionLog10ContrastMinSpinner.ValueChangedFcn = createCallbackFcn(app, @psychometricFunctionLog10ContrastMinSpinnerValueChanged, true);
            app.psychometricFunctionLog10ContrastMinSpinner.Value = -4;
            app.log10contrastmaxLabel = uilabel(app.Panel_11);
            app.log10contrastmaxLabel.Text = 'log10 contrast, max ';
            app.psychometricFunctionLog10ContrastMaxSpinner = uispinner(app.Panel_11);
            app.psychometricFunctionLog10ContrastMaxSpinner.Step = 0.25;
            app.psychometricFunctionLog10ContrastMaxSpinner.Limits = [-6 0];
            app.psychometricFunctionLog10ContrastMaxSpinner.ValueChangedFcn = createCallbackFcn(app, @psychometricFunctionLog10ContrastMaxSpinnerValueChanged, true);
            app.log10contrastdeltaLabel = uilabel(app.Panel_11);
            app.log10contrastdeltaLabel.Text = 'log10 contrast, delta ';
            app.psychometricFunctionLog10ContrastDeltaSpinner = uispinner(app.Panel_11);
            app.psychometricFunctionLog10ContrastDeltaSpinner.Step = 0.01;
            app.psychometricFunctionLog10ContrastDeltaSpinner.Limits = [0.01 0.5];
            app.psychometricFunctionLog10ContrastDeltaSpinner.ValueChangedFcn = createCallbackFcn(app, @psychometricFunctionLog10ContrastDeltaSpinnerValueChanged, true);
            app.psychometricFunctionLog10ContrastDeltaSpinner.Value = 0.05;
            app.slopeminLabel = uilabel(app.Panel_11);
            app.slopeminLabel.Text = 'slope, min ';
            app.psychometricFunctionSlopeMinSpinner = uispinner(app.Panel_11);
            app.psychometricFunctionSlopeMinSpinner.Step = 0.5;
            app.psychometricFunctionSlopeMinSpinner.Limits = [0 Inf];
            app.psychometricFunctionSlopeMinSpinner.ValueChangedFcn = createCallbackFcn(app, @psychometricFunctionSlopeMinSpinnerValueChanged, true);
            app.psychometricFunctionSlopeMinSpinner.Value = 8;
            app.slopemaxLabel = uilabel(app.Panel_11);
            app.slopemaxLabel.Text = 'slope, max ';
            app.psychometricFunctionSlopeMaxSpinner = uispinner(app.Panel_11);
            app.psychometricFunctionSlopeMaxSpinner.Step = 10;
            app.psychometricFunctionSlopeMaxSpinner.Limits = [0 Inf];
            app.psychometricFunctionSlopeMaxSpinner.ValueChangedFcn = createCallbackFcn(app, @psychometricFunctionSlopeMaxSpinnerValueChanged, true);
            app.psychometricFunctionSlopeMaxSpinner.Value = 80;
            app.slopedeltaLabel = uilabel(app.Panel_11);
            app.slopedeltaLabel.Text = 'slope, delta ';
            app.psychometricFunctionSlopeDeltaSpinner = uispinner(app.Panel_11);
            app.psychometricFunctionSlopeDeltaSpinner.Limits = [0 Inf];
            app.psychometricFunctionSlopeDeltaSpinner.ValueChangedFcn = createCallbackFcn(app, @psychometricFunctionSlopeDeltaSpinnerValueChanged, true);
            app.psychometricFunctionSlopeDeltaSpinner.Value = 2;
            app.classifierLabel = uilabel(app.Panel_11);
            app.classifierLabel.Text = 'classifier ';
            app.psychometricFunctionClassifierTypeDropDown = uidropdown(app.Panel_11);
            app.psychometricFunctionClassifierTypeDropDown.Items = {'ideal observer (Poisson)', 'computational observer (SVM)'};
            app.psychometricFunctionClassifierTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @psychometricFunctionClassifierTypeDropDownValueChanged, true);
            app.psychometricFunctionClassifierTypeDropDown.Tooltip = {'The ideal observer classifies responses based on  a maximum likelihood approach and knowledge of the noise-free responses. The computational observer learns to classify responses using SVM trained on noisy responses.'};
            app.psychometricFunctionClassifierTypeDropDown.Value = 'ideal observer (Poisson)';
            app.trainingtrialsLabel = uilabel(app.Panel_11);
            app.trainingtrialsLabel.Text = 'training trials ';
            app.psychometricFunctionClassifierTrainingTrialsSpinner = uispinner(app.Panel_11);
            app.psychometricFunctionClassifierTrainingTrialsSpinner.Limits = [1 Inf];
            app.psychometricFunctionClassifierTrainingTrialsSpinner.ValueDisplayFormat = '%5.0f';
            app.psychometricFunctionClassifierTrainingTrialsSpinner.ValueChangedFcn = createCallbackFcn(app, @psychometricFunctionClassifierTrainingTrialsSpinnerValueChanged, true);
            app.psychometricFunctionClassifierTrainingTrialsSpinner.Value = 1;
            app.testtrialsLabel = uilabel(app.Panel_11);
            app.testtrialsLabel.Text = 'test trials ';
            app.psychometricFunctionClassifierTestTrialsSpinner = uispinner(app.Panel_11);
            app.psychometricFunctionClassifierTestTrialsSpinner.Limits = [1 Inf];
            app.psychometricFunctionClassifierTestTrialsSpinner.ValueDisplayFormat = '%6.0f';
            app.psychometricFunctionClassifierTestTrialsSpinner.ValueChangedFcn = createCallbackFcn(app, @psychometricFunctionClassifierTestTrialsSpinnerValueChanged, true);
            app.psychometricFunctionClassifierTestTrialsSpinner.Value = 128;
            app.contrastlevelsLabel = uilabel(app.Panel_11);
            app.contrastlevelsLabel.Text = 'contrast levels ';
            app.psychometricFunctionContrastLevelsSpinner = uispinner(app.Panel_11);
            app.psychometricFunctionContrastLevelsSpinner.Limits = [2 Inf];
            app.psychometricFunctionContrastLevelsSpinner.ValueChangedFcn = createCallbackFcn(app, @psychometricFunctionContrastLevelsSpinnerValueChanged, true);
            app.psychometricFunctionContrastLevelsSpinner.Value = 5;
            app.spatialfrequencymincdegLabel = uilabel(app.Panel_11);
            app.spatialfrequencymincdegLabel.Text = 'spatial frequency, min (c/deg) ';
            app.csfSpatialFrequencyMinSpinner = uispinner(app.Panel_11);
            app.csfSpatialFrequencyMinSpinner.Limits = [0.01 60];
            app.csfSpatialFrequencyMinSpinner.ValueDisplayFormat = '%4.1f';
            app.csfSpatialFrequencyMinSpinner.ValueChangedFcn = createCallbackFcn(app, @csfSpatialFrequencyMinSpinnerValueChanged, true);
            app.csfSpatialFrequencyMinSpinner.Value = 3;
            app.spatialfrequencymaxcdegLabel = uilabel(app.Panel_11);
            app.spatialfrequencymaxcdegLabel.Text = 'spatial frequency, max (c/deg) ';
            app.csfSpatialFrequencyMaxSpinner = uispinner(app.Panel_11);
            app.csfSpatialFrequencyMaxSpinner.Limits = [0 120];
            app.csfSpatialFrequencyMaxSpinner.ValueChangedFcn = createCallbackFcn(app, @csfSpatialFrequencyMaxSpinnerValueChanged, true);
            app.csfSpatialFrequencyMaxSpinner.Value = 100;
            app.spatialfrequencysamplesLabel = uilabel(app.Panel_11);
            app.spatialfrequencysamplesLabel.Text = 'spatial frequency samples ';
            app.csfSpatialFrequencySamplesSpinner = uispinner(app.Panel_11);
            app.csfSpatialFrequencySamplesSpinner.Limits = [1 20];
            app.csfSpatialFrequencySamplesSpinner.ValueChangedFcn = createCallbackFcn(app, @csfSpatialFrequencySamplesSpinnerValueChanged, true);
            app.csfSpatialFrequencySamplesSpinner.Value = 5;
            app.csfConstantParameterSwitch = uiswitch(app.Panel_11, 'slider');
            app.csfConstantParameterSwitch.Items = {'constant size', 'constant cycles'};
            app.csfConstantParameterSwitch.ValueChangedFcn = createCallbackFcn(app, @csfConstantParameterSwitchValueChanged, true);
            app.csfConstantParameterSwitch.Tooltip = {'The stimulus spatial extent can be either constant size (same size for all spatial frequencies, in which case lower spatial frequency stimuli contain less cycles than higher spatial frequency stimuli) or constant cycles (same number of cycles across all spatial frequencies).'};
            app.csfConstantParameterSwitch.Value = 'constant size';
            app.csfSourceSignalKnob = uiknob(app.Panel_11, 'discrete');
            app.csfSourceSignalKnob.Items = {'cone excitations', 'photocurrents', 'photovoltages', 'midget RGCs', 'parasol RGCs', 'allRGCs'};
            app.csfSourceSignalKnob.ValueChangedFcn = createCallbackFcn(app, @csfSourceSignalKnobValueChanged, true);
            app.csfSourceSignalKnob.Tooltip = {'Selects the source signal to be used for performance assessment'};
            app.csfSourceSignalKnob.Value = 'cone excitations';
            app.psychometricFunctionEstimationMethodSwitch = uiswitch(app.Panel_11, 'slider');
            app.psychometricFunctionEstimationMethodSwitch.Items = {'constant stimuli', 'adaptive (mQuest+)'};
            app.psychometricFunctionEstimationMethodSwitch.ValueChangedFcn = createCallbackFcn(app, @psychometricFunctionEstimationMethodSwitchValueChanged, true);
            app.psychometricFunctionEstimationMethodSwitch.Tooltip = {'Method for estimating the sychometric function. The adaptive method is much faster as it places the examined contrast levels at the most informative positions along the psychometric curve. The constant size method allows the user to determine the examined contrast levels.'};
            app.psychometricFunctionEstimationMethodSwitch.Value = 'constant stimuli';
            app.csfNumberOfConstantCyclesSpinner = uispinner(app.Panel_11);
            app.csfNumberOfConstantCyclesSpinner.Step = 0.1;
            app.csfNumberOfConstantCyclesSpinner.Limits = [0.1 10];
            app.csfNumberOfConstantCyclesSpinner.ValueChangedFcn = createCallbackFcn(app, @csfNumberOfConstantCyclesSpinnerValueChanged, true);
            app.csfNumberOfConstantCyclesSpinner.Enable = 'off';
            app.csfNumberOfConstantCyclesSpinner.Value = 1;
            app.simulussizeatlowestspatialfrequencydegEditFieldLabel_2 = uilabel(app.Panel_11);
            app.simulussizeatlowestspatialfrequencydegEditFieldLabel_2.Enable = 'off';
            app.simulussizeatlowestspatialfrequencydegEditFieldLabel_2.Text = 'simulus size at lowest spatial frequency (deg) ';
            app.stimulusSizeAtLowestSpatialFrequencyEditField = uieditfield(app.Panel_11, 'text');
            app.stimulusSizeAtLowestSpatialFrequencyEditField.Editable = 'off';
            app.stimulusSizeAtLowestSpatialFrequencyEditField.Enable = 'off';
            app.csfRunButton = uibutton(app.Panel_11, 'state');
            app.csfRunButton.ValueChangedFcn = createCallbackFcn(app, @csfRunButtonValueChanged, true);
            app.csfRunButton.Text = 'RUN !';
            app.resetCSFRunButton = uibutton(app.Panel_11, 'push');
            app.resetCSFRunButton.ButtonPushedFcn = createCallbackFcn(app, @resetCSFRunButtonPushed, true);
            app.resetCSFRunButton.Text = 'reset/ abort ';
            app.signalsourceLabel = uilabel(app.Panel_11);
            app.signalsourceLabel.Text = 'signal source';
            app.sigmaLabel = uilabel(app.Panel_11);
            app.sigmaLabel.Text = '/sigma';
            app.psychometricfunctionestimationLabel = uilabel(app.Panel_11);
            app.psychometricfunctionestimationLabel.Text = 'psychometric function estimation';
            app.psychometricFunctionView = uiaxes(app.computationalObserverTab);
            xlabel(app.psychometricFunctionView, 'X')
            ylabel(app.psychometricFunctionView, 'Y')
            zlabel(app.psychometricFunctionView, 'Z')
            app.psychometricFunctionView.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.csfView = uiaxes(app.computationalObserverTab);
            xlabel(app.csfView, 'X')
            ylabel(app.csfView, 'Y')
            zlabel(app.csfView, 'Z')
            app.csfView.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.TabGroupA = uitabgroup(app.GridLayout);
            app.TabGroupA.SelectionChangedFcn = createCallbackFcn(app, @TabGroupASelectionChanged, true);
            app.visualFieldTab = uitab(app.TabGroupA);
            app.visualFieldTab.Title = 'region of interest (visual field)';
            app.Panel_5 = uipanel(app.visualFieldTab);
            app.roiEyeSwitch = uiswitch(app.Panel_5, 'slider');
            app.roiEyeSwitch.Items = {'left eye', 'right eye'};
            app.roiEyeSwitch.ValueChangedFcn = createCallbackFcn(app, @roiEyeSwitchValueChanged, true);
            app.roiEyeSwitch.Value = 'right eye';
            app.polareccentricitydegsLabel = uilabel(app.Panel_5);
            app.polareccentricitydegsLabel.Text = 'polar eccentricity (degs) ';
            app.roiPolarEccentricityKnob = uiknob(app.Panel_5, 'continuous');
            app.roiPolarEccentricityKnob.Limits = [0 360];
            app.roiPolarEccentricityKnob.ValueChangedFcn = createCallbackFcn(app, @roiPolarEccentricityKnobValueChanged, true);
            app.roiPolarEccentricityKnob.ValueChangingFcn = createCallbackFcn(app, @roiPolarEccentricityKnobValueChanging, true);
            app.radialeccentricitydegsLabel = uilabel(app.Panel_5);
            app.radialeccentricitydegsLabel.Text = {'radial eccentricity (degs) '; ''};
            app.roiRadialEccentricitySlider = uislider(app.Panel_5);
            app.roiRadialEccentricitySlider.ValueChangedFcn = createCallbackFcn(app, @roiRadialEccentricitySliderValueChanged, true);
            app.roiRadialEccentricitySlider.ValueChangingFcn = createCallbackFcn(app, @roiRadialEccentricitySliderValueChanging, true);
            app.magnificationfactormicronsdegLabel = uilabel(app.Panel_5);
            app.magnificationfactormicronsdegLabel.Text = 'magnification factor (microns/deg)';
            app.roiMagnificationFactorEditField = uieditfield(app.Panel_5, 'numeric');
            app.roiMagnificationFactorEditField.Editable = 'off';
            app.roiMagnificationFactorEditField.Tooltip = {'Retinal magnification factor (microns/deg) at the currect eccentricity. This changes a little bit with eccentricity,'};
            app.fieldofviewdegsLabel = uilabel(app.Panel_5);
            app.fieldofviewdegsLabel.Text = 'field of view (degs) ';
            app.roiFieldOfViewSlider = uislider(app.Panel_5);
            app.roiFieldOfViewSlider.ValueChangedFcn = createCallbackFcn(app, @roiFieldOfViewSliderValueChanged, true);
            app.roiFieldOfViewSlider.ValueChangingFcn = createCallbackFcn(app, @roiFieldOfViewSliderValueChanging, true);
            app.radialeccentricityscalingLabel = uilabel(app.Panel_5);
            app.radialeccentricityscalingLabel.Text = 'radial eccentricity scaling: ';
            app.roiRadialEccentricityScalingSwitch = uiswitch(app.Panel_5, 'slider');
            app.roiRadialEccentricityScalingSwitch.Items = {'linear', 'log'};
            app.roiRadialEccentricityScalingSwitch.ValueChangedFcn = createCallbackFcn(app, @roiRadialEccentricityScalingSwitchValueChanged, true);
            app.roiRadialEccentricityScalingSwitch.Value = 'linear';
            app.roiEccentricityX = uieditfield(app.Panel_5, 'numeric');
            app.roiEccentricityX.ValueDisplayFormat = '%.1f';
            app.roiEccentricityX.Enable = 'off';
            app.roiEccentricityY = uieditfield(app.Panel_5, 'numeric');
            app.roiEccentricityY.ValueDisplayFormat = '%.1f';
            app.roiEccentricityY.Enable = 'off';
            app.eccentricitydegsLabel = uilabel(app.Panel_5);
            app.eccentricitydegsLabel.Enable = 'off';
            app.eccentricitydegsLabel.Text = '@ eccentricity (degs)';
            app.roiView = uiaxes(app.visualFieldTab);
            zlabel(app.roiView, 'Z')
            app.roiView.XTick = [];
            app.roiView.YTick = [];
            app.stimulusTab = uitab(app.TabGroupA);
            app.stimulusTab.Title = 'stimulus';
            app.Panel_7 = uipanel(app.stimulusTab);
            app.stimulusdurationmsecSpinnerLabel = uilabel(app.Panel_7);
            app.stimulusdurationmsecSpinnerLabel.Text = 'stimulus duration (msec) ';
            app.stimulusDurationSpinner = uispinner(app.Panel_7);
            app.stimulusDurationSpinner.Step = 10;
            app.stimulusDurationSpinner.Limits = [0.1 1000];
            app.stimulusDurationSpinner.ValueChangedFcn = createCallbackFcn(app, @stimulusDurationChanged, true);
            app.stimulusDurationSpinner.Value = 100;
            app.meanluminancecdm2SpinnerLabel = uilabel(app.Panel_7);
            app.meanluminancecdm2SpinnerLabel.Text = 'mean luminance (cd/m2) ';
            app.stimulusMeanLuminanceSpinner = uispinner(app.Panel_7);
            app.stimulusMeanLuminanceSpinner.Step = 10;
            app.stimulusMeanLuminanceSpinner.Limits = [1 1000];
            app.stimulusMeanLuminanceSpinner.ValueChangedFcn = createCallbackFcn(app, @stimulusMeanLuminanceChanged, true);
            app.stimulusMeanLuminanceSpinner.Value = 100;
            app.temporalfrequencyHzSpinnerLabel = uilabel(app.Panel_7);
            app.temporalfrequencyHzSpinnerLabel.Enable = 'off';
            app.temporalfrequencyHzSpinnerLabel.Text = 'temporal frequency (Hz) ';
            app.stimulusTemporalFrequencySpinner = uispinner(app.Panel_7);
            app.stimulusTemporalFrequencySpinner.Step = 10;
            app.stimulusTemporalFrequencySpinner.Limits = [0.5 100];
            app.stimulusTemporalFrequencySpinner.ValueChangedFcn = createCallbackFcn(app, @stimulusTemporalFrequencyChanged, true);
            app.stimulusTemporalFrequencySpinner.Enable = 'off';
            app.stimulusTemporalFrequencySpinner.Value = 100;
            app.orientationdegLabel = uilabel(app.Panel_7);
            app.orientationdegLabel.Text = 'orientation (deg) ';
            app.stimulusOrientationSpinner = uispinner(app.Panel_7);
            app.stimulusOrientationSpinner.Step = 5;
            app.stimulusOrientationSpinner.Limits = [-360 360];
            app.stimulusOrientationSpinner.ValueChangedFcn = createCallbackFcn(app, @stimulusOrientationChanged, true);
            app.stimulusOrientationSpinner.Value = 100;
            app.presentationmodeLabel = uilabel(app.Panel_7);
            app.presentationmodeLabel.Text = 'presentation mode ';
            app.stimulusPresentationModeDropDown = uidropdown(app.Panel_7);
            app.stimulusPresentationModeDropDown.Items = {'flashed', 'drifted', 'counter phase modulated'};
            app.stimulusPresentationModeDropDown.ValueChangedFcn = createCallbackFcn(app, @stimulusPresentationModeChanged, true);
            app.stimulusPresentationModeDropDown.Value = 'flashed';
            app.spatialenvelopeLabel = uilabel(app.Panel_7);
            app.spatialenvelopeLabel.Text = 'spatial envelope ';
            app.stimulusSpatialEnvelopeDropDown = uidropdown(app.Panel_7);
            app.stimulusSpatialEnvelopeDropDown.Items = {'disk', 'rect', 'soft'};
            app.stimulusSpatialEnvelopeDropDown.ValueChangedFcn = createCallbackFcn(app, @stimulusSpatialEnvelopeChanged, true);
            app.stimulusSpatialEnvelopeDropDown.Value = 'rect';
            app.spatialfrequencycdegSpinnerLabel = uilabel(app.Panel_7);
            app.spatialfrequencycdegSpinnerLabel.Text = 'spatial frequency (c/deg) ';
            app.stimulusSpatialFrequencySpinner = uispinner(app.Panel_7);
            app.stimulusSpatialFrequencySpinner.Limits = [0 100];
            app.stimulusSpatialFrequencySpinner.ValueChangedFcn = createCallbackFcn(app, @stimulusSpatialFrequencyChanged, true);
            app.stimulusSpatialFrequencySpinner.Value = 100;
            app.sizedegSpinnerLabel = uilabel(app.Panel_7);
            app.sizedegSpinnerLabel.Text = 'size (deg) ';
            app.stimulusSizeSpinner = uispinner(app.Panel_7);
            app.stimulusSizeSpinner.Limits = [0.1 100];
            app.stimulusSizeSpinner.ValueChangedFcn = createCallbackFcn(app, @stimulusSizeChanged, true);
            app.stimulusSizeSpinner.Value = 100;
            app.resolutionpixelsSpinnerLabel = uilabel(app.Panel_7);
            app.resolutionpixelsSpinnerLabel.Text = 'resolution (pixels) ';
            app.stimulusResolutionSpinner = uispinner(app.Panel_7);
            app.stimulusResolutionSpinner.Step = 64;
            app.stimulusResolutionSpinner.Limits = [1 1000];
            app.stimulusResolutionSpinner.ValueChangedFcn = createCallbackFcn(app, @stimulusResolutionChanged, true);
            app.stimulusResolutionSpinner.Value = 128;
            app.stimulusSpatialPositionXSpinner = uispinner(app.Panel_7);
            app.stimulusSpatialPositionXSpinner.Limits = [-100 100];
            app.stimulusSpatialPositionXSpinner.ValueChangedFcn = createCallbackFcn(app, @stimulusSpatialPositionXChanged, true);
            app.stimulusSpatialPositionXSpinner.Value = 100;
            app.xypositiondegLabel = uilabel(app.Panel_7);
            app.xypositiondegLabel.Text = '(x,y) position (deg) ';
            app.stimulusSpatialPositionYSpinner = uispinner(app.Panel_7);
            app.stimulusSpatialPositionYSpinner.Limits = [-100 100];
            app.stimulusSpatialPositionYSpinner.ValueChangedFcn = createCallbackFcn(app, @stimulusSpatialPositionYChanged, true);
            app.stimulusSpatialPositionYSpinner.Value = 100;
            app.stimulusMosaicCenteredCheckBox = uicheckbox(app.Panel_7);
            app.stimulusMosaicCenteredCheckBox.ValueChangedFcn = createCallbackFcn(app, @stimulusMosaicCenteredChanged, true);
            app.stimulusMosaicCenteredCheckBox.Tooltip = {'Enabling this ensures that the stimulus is always centered on the cone mosaic'};
            app.stimulusMosaicCenteredCheckBox.Text = 'mosaic-centered';
            app.LconecontrastSpinnerLabel = uilabel(app.Panel_7);
            app.LconecontrastSpinnerLabel.Text = 'L-cone contrast (%) ';
            app.stimulusLconeContrastSpinner = uispinner(app.Panel_7);
            app.stimulusLconeContrastSpinner.Step = 0.5;
            app.stimulusLconeContrastSpinner.Limits = [-100 100];
            app.stimulusLconeContrastSpinner.ValueChangedFcn = createCallbackFcn(app, @stimulusLconeContrastChanged, true);
            app.stimulusLconeContrastSpinner.Value = 100;
            app.MconecontrastSpinnerLabel = uilabel(app.Panel_7);
            app.MconecontrastSpinnerLabel.Text = 'M-cone contrast (%) ';
            app.stimulusMconeContrastSpinner = uispinner(app.Panel_7);
            app.stimulusMconeContrastSpinner.Step = 0.5;
            app.stimulusMconeContrastSpinner.Limits = [-100 100];
            app.stimulusMconeContrastSpinner.ValueChangedFcn = createCallbackFcn(app, @stimulusMconeContrastChanged, true);
            app.stimulusMconeContrastSpinner.Value = 100;
            app.SconecontrastSpinnerLabel = uilabel(app.Panel_7);
            app.SconecontrastSpinnerLabel.Text = 'S-cone contrast (%) ';
            app.stimulusSconeContrastSpinner = uispinner(app.Panel_7);
            app.stimulusSconeContrastSpinner.Limits = [-100 100];
            app.stimulusSconeContrastSpinner.ValueChangedFcn = createCallbackFcn(app, @stimulusSconeContrastChanged, true);
            app.stimulusSconeContrastSpinner.Value = 100;
            app.wavelengthsupportminnmSpinnerLabel = uilabel(app.Panel_7);
            app.wavelengthsupportminnmSpinnerLabel.Text = 'wavelength support, min (nm) ';
            app.stimulusWavelengthSupportMinSpinner = uispinner(app.Panel_7);
            app.stimulusWavelengthSupportMinSpinner.Step = 10;
            app.stimulusWavelengthSupportMinSpinner.Limits = [300 800];
            app.stimulusWavelengthSupportMinSpinner.ValueChangedFcn = createCallbackFcn(app, @stimulusWavelengthSupportMinChanged, true);
            app.stimulusWavelengthSupportMinSpinner.Value = 300;
            app.wavelengthsupportmaxnmSpinnerLabel = uilabel(app.Panel_7);
            app.wavelengthsupportmaxnmSpinnerLabel.Text = 'wavelength support, max (nm) ';
            app.stimulusWavelengthSupportMaxSpinner = uispinner(app.Panel_7);
            app.stimulusWavelengthSupportMaxSpinner.Step = 10;
            app.stimulusWavelengthSupportMaxSpinner.Limits = [300 800];
            app.stimulusWavelengthSupportMaxSpinner.ValueChangedFcn = createCallbackFcn(app, @stimulusWavelengthSupportMaxChanged, true);
            app.stimulusWavelengthSupportMaxSpinner.Value = 300;
            app.wavelengthsupportstepsizenmSpinnerLabel = uilabel(app.Panel_7);
            app.wavelengthsupportstepsizenmSpinnerLabel.Text = 'wavelength support, step size (nm) ';
            app.stimulusWavelengthSupportStepSizeSpinner = uispinner(app.Panel_7);
            app.stimulusWavelengthSupportStepSizeSpinner.Step = 5;
            app.stimulusWavelengthSupportStepSizeSpinner.Limits = [1 50];
            app.stimulusWavelengthSupportStepSizeSpinner.ValueChangedFcn = createCallbackFcn(app, @stimulusWavelengthSupportStepSizeChanged, true);
            app.stimulusWavelengthSupportStepSizeSpinner.Value = 50;
            app.stimulusGenerateButton = uibutton(app.Panel_7, 'push');
            app.stimulusGenerateButton.ButtonPushedFcn = createCallbackFcn(app, @stimulusGenerateButtonPushed, true);
            app.stimulusGenerateButton.Text = 'update stimulus chromaticity';
            app.spatialphasedegLabel = uilabel(app.Panel_7);
            app.spatialphasedegLabel.Text = 'spatial phase (deg) ';
            app.stimulusSpatialPhaseSpinner = uispinner(app.Panel_7);
            app.stimulusSpatialPhaseSpinner.Limits = [-360 360];
            app.stimulusSpatialPhaseSpinner.ValueChangedFcn = createCallbackFcn(app, @stimulusSpatialPhaseSpinnerValueChanged, true);
            app.stimulusSpatialPhaseSpinner.Value = 100;
            app.stimulusView = uiaxes(app.stimulusTab);
            zlabel(app.stimulusView, 'Z')
            app.stimulusView.XTick = [];
            app.stimulusView.YTick = [];
            app.performanceAssessmentTab = uitab(app.TabGroupA);
            app.performanceAssessmentTab.Title = 'tasks';
            app.Panel_10 = uipanel(app.performanceAssessmentTab);
            app.exportPipelineButton = uibutton(app.Panel_10, 'push');
            app.exportPipelineButton.ButtonPushedFcn = createCallbackFcn(app, @exportPipelineButtonPushed, true);
            app.exportPipelineButton.Text = 'EXPORT THE PIPELINE !';
            app.exportCSFPDF = uibutton(app.Panel_10, 'push');
            app.exportCSFPDF.ButtonPushedFcn = createCallbackFcn(app, @exportCSFPDFPushed, true);
            app.exportCSFPDF.Text = 'GENERATE CSF PDF';
            app.Panel = uipanel(app.GridLayout);
            app.tabGroupAStatusField = uieditfield(app.Panel, 'text');
            app.tabGroupAStatusField.Editable = 'off';
            app.tabGroupAStatusField.Value = 'left status';
            app.Panel_2 = uipanel(app.GridLayout);
            app.tabGroupBStatusField = uieditfield(app.Panel_2, 'text');
            app.tabGroupBStatusField.Editable = 'off';
            app.tabGroupBStatusField.Value = 'right status';
            % Show the figure after all components are created
            app.mainView.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ISETBioCSFGenerator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.mainView)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.mainView)
        end
    end
end