classdef opticsAndConeMosaic < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        loaddataButton           matlab.ui.control.Button
        pupilmmSliderLabel       matlab.ui.control.Label
        pupilmmSlider            matlab.ui.control.Slider
        wavelengthnmSliderLabel  matlab.ui.control.Label
        wavelengthnmSlider       matlab.ui.control.Slider
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        theConeMosaic;              % the cone mosaic object
        thePSF;                     % the point spread function 3D matrix
        thePSFsupport;               % the spatial support of the PSF
        visualizedWavelenth;        % the wavelength that is currently visualized
        
        computedWavelengths = 450:25:700      % wavelengths for which to compute the PSF
        pupilDiameterMM = 3         % pupil diameter for which to compute the PSF
        eccDegs = [1 0];            % eccentricity (horizontal, vertical) in degrees
        subjectID = 10;             % Polans subject ID
        micronsPerDegree            % microns/deg factor
    end
    
    methods (Access = private)
        
        function generateEccVaryingOpticsAndConeMosaic(app)
              
            % Open progress bar
            hProgressDialog = uiprogressdlg(app.UIFigure,'Title','Status');
            hProgressDialog.Value = 0.2;
            hProgressDialog.Message = 'Loading data';
            
            % Compute microns per degree factor for the target eccentricity
            sizeDegs = 0.1;
            eccRadiusDegs = sqrt(sum(app.eccDegs.^2));
            sizeMicrons = RGCmodels.Watson.convert.sizeVisualDegsToSizeRetinalMicrons(sizeDegs, eccRadiusDegs);
            app.micronsPerDegree = sizeMicrons(1)/sizeDegs(1);
        
            % Compute spacing for cone mosaic appropriate for this eccentricity
            coneSpacingDegs = RGCmodels.Watson.compute.coneSpacingAlongMeridianInRightEyeVisualField(eccRadiusDegs, 'temporal meridian');
            coneSpacingMicrons = RGCmodels.Watson.convert.sizeVisualDegsToSizeRetinalMicrons(coneSpacingDegs, eccRadiusDegs);
            coneApertureMicrons = RGCmodels.Watson.constants.coneApertureToDiameterRatio * coneSpacingMicrons;
            
            hProgressDialog.Value = 0.4;
            hProgressDialog.Message = 'Generating cone mosaic for given eccentricity';
            
            % Generate a 0.2 x 0.2 deg regular hex cone mosaic
            app.theConeMosaic = coneMosaicHex(9, ...
                'fovDegs', 0.2, ...
                'micronsPerDegree', app.micronsPerDegree, ...
                'customLambda', coneSpacingMicrons, ...
                'customInnerSegmentDiameter', coneApertureMicrons ...
            );
            
            hProgressDialog.Value = 0.6;
            hProgressDialog.Message = 'Generating optics for given eccentricity';
            
            % Compute optics for eccentricity  based on the Polans data
            [~, app.thePSF, app.thePSFsupport, ~] = PolansOptics.oiForSubjectAtEccentricity(app.eccDegs, app.subjectID, ...
                app.pupilDiameterMM, app.computedWavelengths, app.micronsPerDegree, ...
                'wavefrontSpatialSamples', 301, ...
                'noLCA', ~true, ...
                'subtractCentralRefraction', true);
            
            % Normalize PSF
            app.thePSF = app.thePSF / max(app.thePSF(:));
            
            % Set the visualized wavelength
            app.visualizedWavelenth = 550;
            
            hProgressDialog.Value = 0.8;
            hProgressDialog.Message = 'Rendering';
            
            % Update the plot
            app.updatePlot();
            
            close(hProgressDialog);
        end
        
        function updatePlot(app)
            cMap = brewermap(1024, '*blues');
            zLevels = logspace(log10(0.05), log10(1), 6);
            psfRangeArcMin = 4;
    
            % Find the visualized wavelength index
            [~,waveIndex] = min(abs(app.computedWavelengths-app.visualizedWavelenth));
            
            % Extract the visualized PSF slice
            theWavePSF = squeeze(app.thePSF(:,:,waveIndex));
            
            % Render the visualized PSF slice
            cla(app.UIAxes)
            PolansOptics.renderPSF(app.UIAxes, app.thePSFsupport, ...
                app.thePSFsupport, theWavePSF, ...
                psfRangeArcMin, zLevels, cMap, 0.8*[1 1 1], ...
                'superimposedConeMosaic', app.theConeMosaic);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: wavelengthnmSlider
        function wavelengthnmSliderValueChanged(app, event)
            
            % Open progress bar
            hProgressDialog = uiprogressdlg(app.UIFigure,'Title','Status');
            hProgressDialog.Value = 0.3;
            hProgressDialog.Message = 'Re-rendering PSF at new wavelength';
            
            app.visualizedWavelenth = app.wavelengthnmSlider.Value;
            app.updatePlot()
            
            % Close progress bar
            close(hProgressDialog)
        end

        % Button pushed function: loaddataButton
        function loaddataButtonPushed(app, event)
             % Compute cone mosaic and optics for this eccentricity,
             % subject and pupil size
             app.generateEccVaryingOpticsAndConeMosaic();
          
        end

        % Value changed function: pupilmmSlider
        function pupilmmSliderValueChanged(app, event)
            
            % Open progress bar
            hProgressDialog = uiprogressdlg(app.UIFigure,'Title','Status');
            hProgressDialog.Value = 0.3;
            hProgressDialog.Message = 'Computing PSF for new pupil size';
            
            app.pupilDiameterMM = app.pupilmmSlider.Value;
            
            % Compute optics for eccentricity  based on the Polans data
            [~, app.thePSF, app.thePSFsupport, ~] = PolansOptics.oiForSubjectAtEccentricity(app.eccDegs, app.subjectID, ...
                app.pupilDiameterMM, app.computedWavelengths, app.micronsPerDegree, ...
                'wavefrontSpatialSamples', 301, ...
                'noLCA', ~true, ...
                'subtractCentralRefraction', true);
            
            % Normalize PSF
            app.thePSF = app.thePSF / max(app.thePSF(:));
            
            % Update progress bar
            hProgressDialog.Value = 0.6;
            hProgressDialog.Message = 'Re-rendering PSF';
            
            % Update the plot
            app.updatePlot();
            
            % Close progress bar
            close(hProgressDialog)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.loaddataButton = uibutton(app.UIFigure, 'push');
            app.loaddataButton.ButtonPushedFcn = createCallbackFcn(app, @loaddataButtonPushed, true);
            app.loaddataButton.Text = 'load data';
            app.pupilmmSliderLabel = uilabel(app.UIFigure);
            app.pupilmmSliderLabel.Text = 'pupil (mm)';
            app.pupilmmSlider = uislider(app.UIFigure);
            app.pupilmmSlider.Limits = [1 4];
            app.pupilmmSlider.ValueChangedFcn = createCallbackFcn(app, @pupilmmSliderValueChanged, true);
            app.pupilmmSlider.Value = 3;
            app.wavelengthnmSliderLabel = uilabel(app.UIFigure);
            app.wavelengthnmSliderLabel.Text = 'wavelength (nm)';
            app.wavelengthnmSlider = uislider(app.UIFigure);
            app.wavelengthnmSlider.Limits = [450 700];
            app.wavelengthnmSlider.MajorTicks = [450 500 550 600 650 700];
            app.wavelengthnmSlider.ValueChangedFcn = createCallbackFcn(app, @wavelengthnmSliderValueChanged, true);
            app.wavelengthnmSlider.MinorTicks = [450 475 500 525 550 575 600 625 650 675 700];
            app.wavelengthnmSlider.Value = 550;
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'space (arc min)')
            ylabel(app.UIAxes, 'space (arc min)')
            app.UIAxes.XLim = [-4 4];
            app.UIAxes.YLim = [-4 4];
            app.UIAxes.YTick = [-4 -2 0 2 4];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = opticsAndConeMosaic

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end